classdef STG2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ESCOTestUIFigure                matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        STOPMOVEButton                  matlab.ui.control.Button
        QUITButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CameraTab                       matlab.ui.container.Tab
        CameraPanel                     matlab.ui.container.Panel
        StartCameraButton               matlab.ui.control.Button
        StopCameraButton                matlab.ui.control.Button
        CrosshairPanel                  matlab.ui.container.Panel
        OffsetYSpinner                  matlab.ui.control.Spinner
        OffsetYSpinnerLabel             matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        OffsetXSpinner                  matlab.ui.control.Spinner
        OffsetXSpinnerLabel             matlab.ui.control.Label
        SizeSlider                      matlab.ui.control.Slider
        SizeSliderLabel                 matlab.ui.control.Label
        ShowCircleCheckBox              matlab.ui.control.CheckBox
        ShowCrosshairCheckBox           matlab.ui.control.CheckBox
        SettingsPanel                   matlab.ui.container.Panel
        SliderExposure                  matlab.ui.control.Slider
        BirghtnessLabel_2               matlab.ui.control.Label
        SliderBrightness                matlab.ui.control.Slider
        BirghtnessLabel                 matlab.ui.control.Label
        ImageCorrectionTab              matlab.ui.container.Tab
        StepSpinner                     matlab.ui.control.Spinner
        StepLabel                       matlab.ui.control.Label
        RangeSpinner                    matlab.ui.control.Spinner
        RangeSpinnerLabel               matlab.ui.control.Label
        Button_Align_rotation           matlab.ui.control.Button
        Button_Run_correction           matlab.ui.control.Button
        Button_CaptureReference         matlab.ui.control.Button
        CircledetectionPanel            matlab.ui.container.Panel
        CirclesizeSpinner               matlab.ui.control.Spinner
        CirclesizeumSpinnerLabel        matlab.ui.control.Label
        CirclesSpinner                  matlab.ui.control.Spinner
        CirclesSpinnerLabel             matlab.ui.control.Label
        SensitivitySpinner              matlab.ui.control.Spinner
        SensitivitySpinnerLabel         matlab.ui.control.Label
        MaxRadiuspxSpinner              matlab.ui.control.Spinner
        MaxRadiuspxSpinnerLabel         matlab.ui.control.Label
        MinRadiuspxSpinner              matlab.ui.control.Spinner
        MinRadiuspxSpinnerLabel         matlab.ui.control.Label
        Button_findCircles              matlab.ui.control.Button
        MotorTab                        matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        DeviceMotionPanel               matlab.ui.container.Panel
        GroupMovePanel                  matlab.ui.container.Panel
        EditStepsGroup                  matlab.ui.control.NumericEditField
        groupDownButton                 matlab.ui.control.Button
        groupRightButton                matlab.ui.control.Button
        groupLeftButton                 matlab.ui.control.Button
        groupUpButton                   matlab.ui.control.Button
        PadMovePanel                    matlab.ui.container.Panel
        EditStepsPad                    matlab.ui.control.NumericEditField
        padDownButton                   matlab.ui.control.Button
        padRightButton                  matlab.ui.control.Button
        padLeftButton                   matlab.ui.control.Button
        padUpButton                     matlab.ui.control.Button
        ManualMotionPanel               matlab.ui.container.Panel
        AbsolutePositionPanel           matlab.ui.container.Panel
        STAGEZmmPanel                   matlab.ui.container.Panel
        DownButton                      matlab.ui.control.Button
        UpButton                        matlab.ui.control.Button
        EditStepsNeedle                 matlab.ui.control.NumericEditField
        downButton                      matlab.ui.control.Button
        rightButton                     matlab.ui.control.Button
        leftButton                      matlab.ui.control.Button
        upButton                        matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        EditStepsMove                   matlab.ui.control.NumericEditField
        STAGEumLabel                    matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        EditStepsRot                    matlab.ui.control.NumericEditField
        cRotButton                      matlab.ui.control.Button
        ccRotButton                     matlab.ui.control.Button
        ANGLELabel                      matlab.ui.control.Label
        RoutineSettingsTab              matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        MakeIVTab                       matlab.ui.container.Tab
        GateSettingsPanel               matlab.ui.container.Panel
        RampVsEditField                 matlab.ui.control.NumericEditField
        RampVsEditFieldLabel            matlab.ui.control.Label
        VperVEditField_2                matlab.ui.control.NumericEditField
        VperVEditField_2Label           matlab.ui.control.Label
        WaitingsEditField               matlab.ui.control.NumericEditField
        WaitingsEditFieldLabel          matlab.ui.control.Label
        EndVVEditField                  matlab.ui.control.NumericEditField
        EndVVEditFieldLabel             matlab.ui.control.Label
        TargetVVEditField               matlab.ui.control.NumericEditField
        TargetVVEditFieldLabel          matlab.ui.control.Label
        InitVVEditField                 matlab.ui.control.NumericEditField
        InitVVEditFieldLabel            matlab.ui.control.Label
        SweepingLabel                   matlab.ui.control.Label
        IVSettingsPanel                 matlab.ui.container.Panel
        BW_corrEditField                matlab.ui.control.NumericEditField
        BW_corrEditFieldLabel           matlab.ui.control.Label
        DirectionEditField              matlab.ui.control.EditField
        DirectionEditFieldLabel         matlab.ui.control.Label
        SettlingTAutorangemsEditField   matlab.ui.control.NumericEditField
        SettlingTAutorangemsEditFieldLabel  matlab.ui.control.Label
        SettlingTimemsEditField         matlab.ui.control.NumericEditField
        SettlingTimemsEditFieldLabel    matlab.ui.control.Label
        RateHzEditField                 matlab.ui.control.NumericEditField
        RateHzEditFieldLabel            matlab.ui.control.Label
        RepeatsEditField                matlab.ui.control.NumericEditField
        RepeatsEditFieldLabel           matlab.ui.control.Label
        MaxIAEditField                  matlab.ui.control.NumericEditField
        MaxIAEditFieldLabel             matlab.ui.control.Label
        PointsEditField                 matlab.ui.control.NumericEditField
        PointsEditFieldLabel            matlab.ui.control.Label
        MinVVEditField                  matlab.ui.control.NumericEditField
        MinVVEditFieldLabel             matlab.ui.control.Label
        StartVVEditField                matlab.ui.control.NumericEditField
        StartVVEditFieldLabel           matlab.ui.control.Label
        MaxVVEditField                  matlab.ui.control.NumericEditField
        MaxVVEditFieldLabel             matlab.ui.control.Label
        VperVEditField                  matlab.ui.control.NumericEditField
        VperVEditFieldLabel             matlab.ui.control.Label
        GateSweepTab                    matlab.ui.container.Tab
        BiasSettingsPanel               matlab.ui.container.Panel
        RampVsEditField_3               matlab.ui.control.NumericEditField
        RampVsEditField_3Label          matlab.ui.control.Label
        EndVVEditField_2                matlab.ui.control.NumericEditField
        EndVVEditField_2Label           matlab.ui.control.Label
        VperVEditField_4                matlab.ui.control.NumericEditField
        VperVEditField_4Label           matlab.ui.control.Label
        dVVEditField                    matlab.ui.control.NumericEditField
        dVVEditFieldLabel               matlab.ui.control.Label
        MaxVVEditField_3                matlab.ui.control.NumericEditField
        MaxVVEditField_3Label           matlab.ui.control.Label
        MinVVEditField_3                matlab.ui.control.NumericEditField
        MinVVEditField_3Label           matlab.ui.control.Label
        InitVVEditField_2               matlab.ui.control.NumericEditField
        InitVVEditField_2Label          matlab.ui.control.Label
        GateSettingsPanel_2             matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        BW_corrEditField_2              matlab.ui.control.NumericEditField
        BW_corrEditField_2Label         matlab.ui.control.Label
        DirectionEditField_2            matlab.ui.control.EditField
        DirectionEditField_2Label       matlab.ui.control.Label
        SettlingTAutorangemsEditField_2  matlab.ui.control.NumericEditField
        SettlingTAutorangemsEditField_2Label  matlab.ui.control.Label
        SettlingTimemsEditField_2       matlab.ui.control.NumericEditField
        SettlingTimemsEditField_2Label  matlab.ui.control.Label
        RateHzEditField_2               matlab.ui.control.NumericEditField
        RateHzEditField_2Label          matlab.ui.control.Label
        RepeatsEditField_2              matlab.ui.control.NumericEditField
        RepeatsEditField_2Label         matlab.ui.control.Label
        PointsEditField_2               matlab.ui.control.NumericEditField
        PointsEditField_2Label          matlab.ui.control.Label
        MinVVEditField_2                matlab.ui.control.NumericEditField
        MinVVEditField_2Label           matlab.ui.control.Label
        StartVVEditField_2              matlab.ui.control.NumericEditField
        StartVVEditField_2Label         matlab.ui.control.Label
        MaxVVEditField_2                matlab.ui.control.NumericEditField
        MaxVVEditField_2Label           matlab.ui.control.Label
        VperVEditField_3                matlab.ui.control.NumericEditField
        VperVEditField_3Label           matlab.ui.control.Label
        StabilityTab                    matlab.ui.container.Tab
        GateSettingsPanel_3             matlab.ui.container.Panel
        WaitsEditField                  matlab.ui.control.NumericEditField
        WaitsEditFieldLabel             matlab.ui.control.Label
        DirectionEditField_4            matlab.ui.control.EditField
        DirectionEditField_4Label       matlab.ui.control.Label
        RampVsEditField_4               matlab.ui.control.NumericEditField
        RampVsEditField_4Label          matlab.ui.control.Label
        EndVVEditField_3                matlab.ui.control.NumericEditField
        EndVVEditField_3Label           matlab.ui.control.Label
        VperVEditField_6                matlab.ui.control.NumericEditField
        VperVEditField_6Label           matlab.ui.control.Label
        dVVEditField_2                  matlab.ui.control.NumericEditField
        dVVEditField_2Label             matlab.ui.control.Label
        MaxVVEditField_5                matlab.ui.control.NumericEditField
        MaxVVEditField_5Label           matlab.ui.control.Label
        MinVVEditField_5                matlab.ui.control.NumericEditField
        MinVVEditField_5Label           matlab.ui.control.Label
        InitVVEditField_3               matlab.ui.control.NumericEditField
        InitVVEditField_3Label          matlab.ui.control.Label
        IVSettingsPanel_2               matlab.ui.container.Panel
        MaxIAEditField_2                matlab.ui.control.NumericEditField
        MaxIAEditField_2Label           matlab.ui.control.Label
        BW_corrEditField_3              matlab.ui.control.NumericEditField
        BW_corrEditField_3Label         matlab.ui.control.Label
        DirectionEditField_3            matlab.ui.control.EditField
        DirectionEditField_3Label       matlab.ui.control.Label
        TAutorangemsEditField           matlab.ui.control.NumericEditField
        TAutorangemsEditFieldLabel      matlab.ui.control.Label
        SettlingTimemsEditField_3       matlab.ui.control.NumericEditField
        SettlingTimemsEditField_3Label  matlab.ui.control.Label
        RateHzEditField_3               matlab.ui.control.NumericEditField
        RateHzEditField_3Label          matlab.ui.control.Label
        RepeatsEditField_3              matlab.ui.control.NumericEditField
        RepeatsEditField_3Label         matlab.ui.control.Label
        PointsEditField_3               matlab.ui.control.NumericEditField
        PointsEditField_3Label          matlab.ui.control.Label
        MinVVEditField_4                matlab.ui.control.NumericEditField
        MinVVEditField_4Label           matlab.ui.control.Label
        StartVVEditField_3              matlab.ui.control.NumericEditField
        StartVVEditField_3Label         matlab.ui.control.Label
        MaxVVEditField_4                matlab.ui.control.NumericEditField
        MaxVVEditField_4Label           matlab.ui.control.Label
        VperVEditField_5                matlab.ui.control.NumericEditField
        VperVEditField_5Label           matlab.ui.control.Label
        GeneralSettingsTab              matlab.ui.container.Tab
        NrEditField                     matlab.ui.control.EditField
        LabelEditField_2Label           matlab.ui.control.Label
        LabelEditField                  matlab.ui.control.EditField
        DeviceLabelEditFieldLabel       matlab.ui.control.Label
        RotationSwitch                  matlab.ui.control.ToggleSwitch
        RotationLabel                   matlab.ui.control.Label
        DeviceNrEditField               matlab.ui.control.NumericEditField
        DeviceNrEditFieldLabel          matlab.ui.control.Label
        ABORTINITIALIZATIONButton       matlab.ui.control.Button
        INITIALIZESTAGEButton           matlab.ui.control.Button
        StageCorrectionFactorsPanel     matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        HysthfactorYdirectionEditField  matlab.ui.control.NumericEditField
        HysthfactorYdirectionEditFieldLabel  matlab.ui.control.Label
        HysthfactorXdirectionEditField  matlab.ui.control.NumericEditField
        HysthfactorXdirectionEditFieldLabel  matlab.ui.control.Label
        StepfactorYdirectionEditField   matlab.ui.control.NumericEditField
        StepfactorYdirectionEditFieldLabel  matlab.ui.control.Label
        StepfactorXdirectionEditField   matlab.ui.control.NumericEditField
        StepfactorXdirectionEditFieldLabel  matlab.ui.control.Label
        GeometryPanel                   matlab.ui.container.Panel
        CellrowsEditField               matlab.ui.control.NumericEditField
        CellcolumnsEditField_2Label_2   matlab.ui.control.Label
        CellcolumnsEditField            matlab.ui.control.NumericEditField
        CellcolumnsEditField_2Label     matlab.ui.control.Label
        SelectNrofCellsLabel            matlab.ui.control.Label
        SelectDeviceLabel               matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        SamplePanel                     matlab.ui.container.Panel
        newFolder                       matlab.ui.control.Button
        SampleNameEditField             matlab.ui.control.EditField
        SampleNameEditFieldLabel        matlab.ui.control.Label
        SaveDirectoryEditField          matlab.ui.control.EditField
        SaveDirectoryEditFieldLabel     matlab.ui.control.Label
        StagePanel                      matlab.ui.container.Panel
        VelocityZEditField              matlab.ui.control.NumericEditField
        VelocityXYEditFieldLabel_2      matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        VelocityXYEditField             matlab.ui.control.NumericEditField
        VelocityXYEditFieldLabel        matlab.ui.control.Label
        MeasureTab                      matlab.ui.container.Tab
        VperVEditField_7                matlab.ui.control.NumericEditField
        VperVEditField_7Label           matlab.ui.control.Label
        TestLabel                       matlab.ui.control.Label
        ReferenceLabel                  matlab.ui.control.Label
        CaptureSwitch                   matlab.ui.control.ToggleSwitch
        CaptureSwitchLabel              matlab.ui.control.Label
        MotionSwitch                    matlab.ui.control.ToggleSwitch
        MotionSwitchLabel               matlab.ui.control.Label
        RuntimesEditField               matlab.ui.control.NumericEditField
        RuntimesEditFieldLabel          matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        RLamp                           matlab.ui.control.Lamp
        RLampLabel                      matlab.ui.control.Label
        LLamp                           matlab.ui.control.Lamp
        LLampLabel                      matlab.ui.control.Label
        DropDown_needleSide             matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        STOPButton                      matlab.ui.control.Button
        TrigSwitchBoxButton             matlab.ui.control.Button
        AbortButton                     matlab.ui.control.Button
        PAUSEButton                     matlab.ui.control.Button
        ProcessesPanel                  matlab.ui.container.Panel
        STARTDEVICEButton               matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        OperationsSelection             matlab.ui.control.DropDown
        ClearListButton                 matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        STARTCHIPButton                 matlab.ui.control.Button
        ContactButton                   matlab.ui.control.Button
        NeedlePlot                      matlab.ui.control.UIAxes
        DataTab                         matlab.ui.container.Tab
        GenerateHysthogramButton        matlab.ui.control.Button
        spreadsheetDropDown             matlab.ui.control.DropDown
        GenerateSpreadsheetButton       matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        UIvideo                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)

        % =================================================================
        % Camera setup
        cameraSettings      % application Settings from Class camSettings
        CameraDAQTimer      % Timer for Camera DAQ
        Vid                 % Video Object
        hImage              % Handle for the image object
        hLineV
        hLineH
        hCircle
        % vidSrc              % Image acquisition Toolbox
        Frame               % Image Data Frame
        Frame_reference     % Reference image for image correction
        tmp_ref_centers     % Centers for reference image for image correction
        tmp_ref_radii       % Radii for reference image for image correction
        Frame_reference_centers = {}     % Centers for multi reference image for automation
        Frame_reference_radii = {}    % Radii for multi reference image for automation
        % =================================================================
        % Motorized stage setup
        Stage                 % ESCO Motor Object
        % MotorPollTimer      % Timer for polling Status & Position
        maxStep = 1000
        UnitMult = 1e-6;
        % =================================================================
        % Layout and Device Geometry
        Layout              % Device Layout settings
        currentLayout       % Layout Selector
        deviceCoordinates   % Generated coordinates to perform scan
        chipRefDevID        % Device ID of the alignment reference (set at measureChip start)
        % =================================================================
        % Handling hardware issues
        flagStop            % Emergency Stop
        flagPause           % Flag for Temporary Issues
        testingMovement = 0 % Flag for testing chip scanning
        isRef_needed = 1;   % Flag for capturing new references when scanning
        failedDevices = 0;
        % =================================================================
        % ADwin connection and usage
        ADwinSettings       % Struct for general ADwin settings
        measureObj         % For measurements (IV, Stability, etc.)
        needleContact       % Object for needle contacting
        switchingBox        % Object to invert switchbox
        makeIV              % Object to perform IV
        makeGateSweep       % Object to perform GateSweep
        makeStability       % Object to perform Stability Diagram

    end

    methods(Static)
        function DetectedConfig = setStageController()
            fprintf('[Init] Scanning available COM ports...\n');
            availablePorts = serialportlist("available");

            if isempty(availablePorts)
                error('No serial ports found! Check USB connections.');
            end

            AddrMap = containers.Map({1, 2, 3, 0}, {'X', 'Y', 'Z', 'Rot'});
            DetectedConfig = struct();
            foundAxes = {};

            % 1. PROBE PORTS
            for i = 1:length(availablePorts)
                port = availablePorts(i);
                fprintf('   Probing %s... ', port);
                try
                    % 1-arg constructor for probe
                    m = StepperMotor(port);
                    addr = m.get_address();
                    if ischar(addr) || isstring(addr), addr = str2double(addr); end

                    if isKey(AddrMap, addr)
                        axisName = AddrMap(addr);
                        fprintf('FOUND %s (Addr %d)\n', axisName, addr);
                        DetectedConfig.(axisName).Port = port;
                        DetectedConfig.(axisName).Addr = addr;
                        foundAxes{end+1} = axisName;
                    else
                        fprintf('Unknown (Addr %d)\n', addr);
                    end
                    delete(m);
                catch
                    fprintf('No response.\n');
                end
            end

            if isempty(foundAxes)
                fprintf(2, 'No valid stages found.\n');
                return;
            end
        end

    end

    methods (Access = public)

        % Methods to use the Camera object

        % Button pushed function: CaptureImageButton
        function captureImage(app, file, path)

            if isrunning(app.Vid)
                % Capture the current frame
                frame = snapshot(app.Vid);

                if isequal(file, 0) || isequal(path, 0)
                    uialert(app.ESCOTestUIFigure, 'Image save cancelled.', 'Cancelled');
                else
                    % Full path for the saved image
                    % file = sprintf("%s.png", file);
                    fullFileName = fullfile(path, file);

                    % Save the image
                    imwrite(frame, fullFileName);


                end

            else
                % Notify the user that the camera is not running
                uialert(app.ESCOTestUIFigure, 'Camera is not running.', 'Error');
            end
        end


        function timerCameraDAQ(app, ~, ~)
            % The image is now updated automatically by 'preview'.
            % We only need to update the Crosshair/Circle overlays here.
            
            try
                % 1. Update Crosshairs
                if app.ShowCrosshairCheckBox.Value && isvalid(app.hLineV)
                    app.CalcCC(); % Update coordinates
                    
                    app.hLineV.Visible = 'on';
                    app.hLineH.Visible = 'on';
                    
                    app.hLineV.XData = [app.cameraSettings.Video.CHC.Xc, app.cameraSettings.Video.CHC.Xc];
                    app.hLineV.YData = [app.cameraSettings.Video.CHC.Yt, app.cameraSettings.Video.CHC.Yb];
                    app.hLineH.XData = [app.cameraSettings.Video.CHC.Xl, app.cameraSettings.Video.CHC.Xr];
                    app.hLineH.YData = [app.cameraSettings.Video.CHC.Yc, app.cameraSettings.Video.CHC.Yc];
                elseif isvalid(app.hLineV)
                    app.hLineV.Visible = 'off';
                    app.hLineH.Visible = 'off';
                end
    
                % 2. Update Circle
                if app.ShowCircleCheckBox.Value && isvalid(app.hCircle)
                    app.hCircle.Visible = 'on';
                    app.hCircle.Position = [app.cameraSettings.Video.CHC.Xc - 20, ...
                                            app.cameraSettings.Video.CHC.Yc - 20, 40, 40];
                elseif isvalid(app.hCircle)
                    app.hCircle.Visible = 'off';
                end
                
                % 3. Draw updates
                drawnow limitrate;
                
            catch
                % Fail silently
            end
        end

        function CalcCC(app)    % Calculate Crosshair Coordinates
            app.cameraSettings.Video.CHC.Xc = app.cameraSettings.Video.VidResX/2 + app.OffsetXSpinner.Value;                       % X-Center
            app.cameraSettings.Video.CHC.Yc = app.cameraSettings.Video.VidResY/2 + app.OffsetYSpinner.Value;                       % Y-Center
            Xleft = app.cameraSettings.Video.CHC.Xc - app.SizeSlider.Value;
            if Xleft < 0
                app.cameraSettings.Video.CHC.Xl = 0;
            else
                app.cameraSettings.Video.CHC.Xl = Xleft;
            end
            Xright = app.cameraSettings.Video.CHC.Xc + app.SizeSlider.Value;
            if Xright > app.cameraSettings.Video.VidResX
                app.cameraSettings.Video.CHC.Xr = app.cameraSettings.Video.VidResX;
            else
                app.cameraSettings.Video.CHC.Xr = Xright;
            end
            Ytop = app.cameraSettings.Video.CHC.Yc - app.SizeSlider.Value;
            if Ytop < 0
                app.cameraSettings.Video.CHC.Yt = 0;
            else
                app.cameraSettings.Video.CHC.Yt = Ytop;
            end
            Ybottom = app.cameraSettings.Video.CHC.Yc + app.SizeSlider.Value;
            if Ybottom > app.cameraSettings.Video.VidResY
                app.cameraSettings.Video.CHC.Yb = app.cameraSettings.Video.VidResY;
            else
                app.cameraSettings.Video.CHC.Yb = Ybottom;
            end
        end

        % =================================================================
        % Generate a structure with device coordinates based on the current
        % selected layout, or a .xlsx file iput by the user for more
        % particular devices

        function app = getCustomCoordinatesFromFile(app)

            % Prompt the user to select an Excel file
            [file, path] = uigetfile({'*.xlsx', 'Excel files (*.xlsx)'}, 'Select the device coordinates file');

            % Check if the user canceled the file selection
            if isequal(file, 0) || isequal(path, 0)
                disp('User canceled the file selection.');
                return;
            else
                filePath = fullfile(path, file); % Concatenate path and file name
            end

            % Read the device coordinates from an Excel file
            dataTable = readtable(filePath);

            % Filter rows where either deviceX or deviceY is missing
            validRows = ~isnan(dataTable.deviceX) & ~isnan(dataTable.deviceY);
            filteredTable = dataTable(validRows, :);

            % Initialize the structure with arrays from the filtered table
            app.deviceCoordinates = struct('deviceID', [], 'deviceX', [], 'deviceY', []);

            app.deviceCoordinates.deviceID = filteredTable.deviceID;
            app.deviceCoordinates.deviceX = filteredTable.deviceX;
            app.deviceCoordinates.deviceY = filteredTable.deviceY;
        end

        % Preallocate arrays in `generateDeviceCoordinates`
        function app = generateDeviceCoordinates(app)
            layout = app.Layout;
            currLayout = app.currentLayout;

            % Preallocate the structure with arrays of appropriate size
            numDevices = layout{currLayout}.getxCells() * layout{currLayout}.getyCells() * layout{currLayout}.getRows() * layout{currLayout}.getColumns();
            app.deviceCoordinates.deviceID = zeros(1, numDevices);
            app.deviceCoordinates.deviceX = zeros(1, numDevices);
            app.deviceCoordinates.deviceY = zeros(1, numDevices);

            deviceID = 1;

            xCellWidth = layout{currLayout}.getColumns() * layout{currLayout}.getWidth() + layout{currLayout}.getxLine();
            yCellHeight = layout{currLayout}.getRows() * layout{currLayout}.getHeight() + layout{currLayout}.getyLine();

            for xcell = 1:layout{currLayout}.getxCells()
                for ycell = 1:layout{currLayout}.getyCells()
                    cellXOrigin = (xcell - 1) * xCellWidth;
                    cellYOrigin = (ycell - 1) * yCellHeight;

                    for column = 1:layout{currLayout}.getColumns()
                        for row = 1:layout{currLayout}.getRows()

                            deviceX = cellXOrigin + (column - 1) * layout{currLayout}.getWidth();
                            deviceY = cellYOrigin + (row - 1) * layout{currLayout}.getHeight();

                            % Assign each new value to the respective field in the structure
                            app.deviceCoordinates.deviceID(deviceID) = deviceID;
                            app.deviceCoordinates.deviceX(deviceID) = deviceX;
                            app.deviceCoordinates.deviceY(deviceID) = deviceY;

                            deviceID = deviceID + 1;
                        end
                    end
                end
            end

            app.deviceCoordinates.diffX = diff(app.deviceCoordinates.deviceX);
            app.deviceCoordinates.diffY = diff(app.deviceCoordinates.deviceY);

        end


        % =================================================================
        % Needle contact routine

        function contactFlag = contactFunction(app, varargin)

            needleSide = varargin{1};
            if nargin == 3
                axes = varargin{2};
            else
                axes = [];
            end

            contactFlag = 0;

            if ~isempty(axes)
                hold(app.NeedlePlot, "off");
                cla(axes);
                hold(axes, "on");
                line = animatedline('Color','black','LineWidth',1.5,'Parent', axes);
                yline(axes, app.needleContact.threshold, '--r', 'LineWidth', 1.5);
            end

            app.needleContact.startRoutine;

            % plot realtime
            previous_counter = 0;
            pause(0.2);
            run = true;

            while run && Get_Par(19) > 0 && ~contactFlag

                while (app.flagPause)
                    pause(0.1)
                end
                if (app.flagStop)
                    break;
                end

                run = Process_Status(2);
                actual_time = Get_Par(19) - 1;

                % get current and update plot
                % temp = zeros(1, actual_time - previous_counter);

                try
                    pause(0.1)
                    currentValue = abs(GetData_Double(2, previous_counter + 1, actual_time - previous_counter));
                    if ~isempty(axes)
                        addpoints(line, app.needleContact.Gate.time(previous_counter + 1 : actual_time), currentValue);
                        drawnow limitrate
                    end
                end

                previous_counter = actual_time;

                if sum(currentValue > app.needleContact.threshold) > 0

                    contactFlag = 1;

                    switch needleSide

                        case 'Left'
                            app.LLamp.Color = 'green';

                        case 'Right'
                            app.RLamp.Color = 'green';
                    end

                else

                    switch needleSide

                        case 'Left'
                            app.Stage.MoveZ(-10 * app.UnitMult);

                        case 'Right'
                            app.Stage.MoveZ(-10 * app.UnitMult);
                    end

                end

            end

            for i=1:10
                Stop_Process(i);
            end

        end

        % =================================================================
        % Moving stage to scan the whole layout, based on the generated
        % device coordinates

        function executeRoutines(app, path, devID)

            close all

            if devID == 0
                devID = app.ADwinSettings.sample;
            else
                devID = sprintf("%s-%d", app.ADwinSettings.sample, devID);
            end

            for itemList = 1:length(app.ListBox.Items)

                if app.flagStop

                    break

                end

                while (app.flagPause)

                    pause(0.1)

                end

                switch app.ListBox.Items{itemList}

                    case 'Capture Image'
                        filePic = sprintf('%s.jpg', devID);
                        app.captureImage(filePic, path);

                    case 'Make IV'

                        app.makeIV.startRoutine(devID, path);

                    case 'Make Stability Diagram'

                        app.makeStability.startRoutine(devID, path);

                    case 'Make Gate Sweep'

                        app.makeGateSweep.startRoutine(devID, path);

                end

            end


        end

        function moveDevice(app, devID)
            refX = app.deviceCoordinates.deviceX(app.chipRefDevID);
            refY = app.deviceCoordinates.deviceY(app.chipRefDevID);
            targetX = (app.deviceCoordinates.deviceX(devID) - refX) * app.UnitMult;
            targetY = -(app.deviceCoordinates.deviceY(devID) - refY) * app.UnitMult;
            app.Stage.MoveToX(targetX);
            app.Stage.MoveToY(targetY);
        end

        function measureChip(app)
            warnString = sprintf('Align on Device %0d', app.DeviceNrEditField.Value);

            mydlg = warndlg('Put Switchbox on Yellow!!', 'Warning');
            waitfor(mydlg);

            % app.Motor.move_leftProbe(50); % Move needles back up before moving
            app.LLamp.Color = 'red';
            % app.Motor.move_rightProbe(50);
            app.RLamp.Color = 'red';

            % app.CirclesSpinner.Value = app.Layout{app.currentLayout}.getColumns;
            % app.CirclesSpinner.Value = 3;

            if app.DeviceNrEditField.Value >= (((app.Layout{app.currentLayout}.getxCells - 1) * app.Layout{app.currentLayout}.getyCells * (app.Layout{app.currentLayout}.getRows * app.Layout{app.currentLayout}.getColumns)) + 1)
                isLastColumn = 1;
            else
                isLastColumn = 0;
            end

            if app.isRef_needed
                mydlg = warndlg('Align on top-left device for reference capture', 'Warning');
                waitfor(mydlg);
                app.captureMultiReference(isLastColumn);
            end

            mydlg = warndlg(warnString, 'Warning');
            waitfor(mydlg);

            % Store reference device and zero XY stage at current (aligned) position
            app.chipRefDevID = app.DeviceNrEditField.Value;
            app.Stage.ZeroXY();

            % Open log file once
            logFilePath = fullfile(app.ADwinSettings.save_dir, 'contact_failures_log.txt');
            logFile = fopen(logFilePath, 'a');  % 'a' mode to append data

            % for devID = app.DeviceNrEditField.Value:(length(app.deviceCoordinates.deviceID) - 1)
            for devID = app.DeviceNrEditField.Value:(length(app.deviceCoordinates.deviceID))
                if app.flagStop
                    break
                end
                while (app.flagPause)
                    pause(0.1);
                end
                if ~(devID == app.DeviceNrEditField.Value)
                    app.moveDevice(devID);
                end
                if mod(devID, (app.Layout{app.currentLayout}.getRows)) == 1 && ~(devID == app.DeviceNrEditField.Value)
                    if app.currentLayout == 1

                        app.moveDevice(devID - 1); %add if very inconsistent between cells

                    elseif app.currentLayout == 2

                        app.Stage.MoveY(-200 * app.UnitMult);

                    end

                    if mod(devID, (app.Layout{app.currentLayout}.getRows * app.Layout{app.currentLayout}.getColumns)) == 1
                        app.runImageCorrection(app.Frame_reference_centers{1});
                        if devID == (((app.Layout{app.currentLayout}.getxCells - 1) * app.Layout{app.currentLayout}.getyCells * (app.Layout{app.currentLayout}.getRows * app.Layout{app.currentLayout}.getColumns)) + 1)
                            isLastColumn = 1;
                            app.captureMultiReference(isLastColumn);
                            app.Stage.MoveX((-app.Layout{app.currentLayout}.getColumns * app.UnitMult) * app.Layout{app.currentLayout}.getWidth);
                        end
                    elseif mod(devID, (app.Layout{app.currentLayout}.getRows * app.Layout{app.currentLayout}.getColumns)) == 6
                        if isLastColumn
                            app.CirclesSpinner.Value = app.CirclesSpinner.Value - 1;
                            app.runImageCorrection(app.Frame_reference_centers{2});
                            app.CirclesSpinner.Value = app.CirclesSpinner.Value + 1;
                        else
                            app.runImageCorrection(app.Frame_reference_centers{2});
                        end
                    elseif mod(devID, (app.Layout{app.currentLayout}.getRows * app.Layout{app.currentLayout}.getColumns)) == 11
                        if isLastColumn
                            app.CirclesSpinner.Value = app.CirclesSpinner.Value - 2;
                            app.runImageCorrection(app.Frame_reference_centers{3});
                            app.CirclesSpinner.Value = app.CirclesSpinner.Value + 2;
                        else
                            app.runImageCorrection(app.Frame_reference_centers{3});
                        end
                    end
                end

                if app.flagStop
                    close all;
                    break
                end

                pause(2);

                while (app.flagPause)
                    pause(0.1);
                end

                if ~app.testingMovement
                    contactFlag = app.contactFunction('Left');
                    if ~contactFlag
                        app.Stage.MoveZ(30 * app.UnitMult);
                        contactFlag = app.contactFunction('Left');
                        if ~contactFlag
                            fprintf(logFile, 'Left contact failed on device %d\n', devID);  % Log the failure
                            app.failedDevices = app.failedDevices + 1;
                            app.Stage.MoveZ(50 * app.UnitMult);
                            app.DeviceNrEditField.Value = devID;
                            continue;
                        end
                    end
                    app.switchingBox.startRoutine;
                    pause(2);
                    contactFlag = app.contactFunction('Right');
                    if ~contactFlag
                        app.Stage.MoveZ(30 * app.UnitMult);
                        contactFlag = app.contactFunction('Right');
                        if ~contactFlag
                            fprintf(logFile, 'Right contact failed on device %d\n', devID);  % Log the failure
                            app.failedDevices = app.failedDevices + 1;
                            app.Stage.MoveZ(50 * app.UnitMult);
                            app.switchingBox.startRoutine;
                            app.DeviceNrEditField.Value = devID;
                            continue;
                        end
                    end
                    app.switchingBox.startRoutine;
                    pause(2);
                    app.executeRoutines(app.ADwinSettings.save_dir, devID);

                    app.Stage.MoveZ(30 * app.UnitMult); % Move needles back up before moving
                    app.LLamp.Color = 'red';
                    app.Stage.MoveZ(30 * app.UnitMult);
                    app.RLamp.Color = 'red';
                end

                app.DeviceNrEditField.Value = devID;
            end

            % Close log file
            fclose(logFile);

            if ~app.flagStop
                warndlg('Chip done!', 'Warning');
            else
                warndlg('Scan aborted!', 'Warning');
            end
        end


        function [Centers, Radii, Image_data_red] = detectCircles(app)

            close all

            % get frame
            frame = snapshot(app.Vid);
            resizedFrame = imresize(frame, 0.25);

            % Cut out the rightmost 10% of the resized frame
            [~, cols, ~] = size(resizedFrame);
            if app.currentLayout == 1

                cutCols = round(cols * 0.9);

            elseif app.currentLayout == 2

                cutCols = round(cols * 1);

            end

            resizedFrame = resizedFrame(:, 1:cutCols, :);

            Image_data_red = adapthisteq(resizedFrame(:,:,1));


            pixelTolerance = 10;

            % loop over sensitivity to find number of circles

            Radii = [];
            Sensitivity = app.SensitivitySpinner.Value;
            correctCircles = 0;
            while Sensitivity < 1 && ~correctCircles

                [Centers, Radii] = imfindcircles(Image_data_red,[app.MinRadiuspxSpinner.Value app.MaxRadiuspxSpinner.Value],'ObjectPolarity','bright','Sensitivity',Sensitivity);

                if numel(Radii) >= app.CirclesSpinner.Value

                    % Initialize an empty matrix to hold the groups of valid circles
                    validGroups = [];

                    % Number of circles detected
                    N = size(Centers, 1);
                    numCircles = app.CirclesSpinner.Value;

                    if numCircles == 1
                        % If only one circle is needed, all detected circles are valid
                        validGroups = (1:N)';
                    else
                        % Check each combination of circles
                        combs = nchoosek(1:N, numCircles);
                        for c = 1:size(combs, 1)
                            groupIndices = combs(c, :);
                            yCoords = Centers(groupIndices, 2);

                            % Check if these centers are approximately on the same y-line
                            if all(abs(yCoords - mean(yCoords)) <= pixelTolerance)
                                % Store the indices of the valid group
                                validGroups = [validGroups; groupIndices];
                            end
                        end
                    end

                    % If any valid groups are found, select the closest group of circles
                    if ~isempty(validGroups)
                        if size(validGroups, 1) == 1
                            bestGroup = validGroups;
                        else
                            minMaxDistance = inf;
                            bestGroup = [];

                            for g = 1:size(validGroups, 1)
                                groupIndices = validGroups(g, :);
                                xCoords = Centers(groupIndices, 1);
                                maxDistance = max(xCoords) - min(xCoords);

                                if maxDistance < minMaxDistance
                                    minMaxDistance = maxDistance;
                                    bestGroup = groupIndices;
                                end
                            end
                        end

                        % Filter the centers to only include those in the best group
                        Centers = Centers(bestGroup, :);
                        correctCircles = 1;
                    else
                        correctCircles = 0;
                    end
                end

                Sensitivity = Sensitivity + 0.005;
            end

            fprintf('Found %02d circles', numel(Radii));
            Radii

            % sort by x position
            [~, idx] = sort(Centers(:,1));
            Centers = [Centers(idx, 1) Centers(idx, 2)];
            Radii = Radii(idx);

        end

        function [ref_centers, ref_radii] = captureReference(app)

            [Centers, Radii, Image_data_red] = app.detectCircles;

            ref_centers = Centers;
            ref_radii = Radii;

            % show output
            h = figure; hold on;
            imshow(Image_data_red)
            viscircles(ref_centers, ref_radii,'EdgeColor','b');

            pause(5);

            try
                if ~isempty(h)
                    close(h);
                end
            end

        end

        function captureMultiReference(app, isLastColumn)

            if ~isLastColumn

                for i = 1:(app.Layout{app.currentLayout}.getColumns)

                    if ~app.flagStop

                        [app.Frame_reference_centers{i}, app.Frame_reference_radii{i}] = app.captureReference;
                        app.Stage.MoveX(app.Layout{app.currentLayout}.getWidth * app.UnitMult);

                    end

                end

            else

                saver = app.CirclesSpinner.Value;

                for i = 1:(app.Layout{app.currentLayout}.getColumns)

                    if ~app.flagStop

                        [app.Frame_reference_centers{i}, app.Frame_reference_radii{i}] = app.captureReference;
                        app.Stage.MoveX(app.Layout{app.currentLayout}.getWidth * app.UnitMult);

                    end

                    app.CirclesSpinner.Value = app.CirclesSpinner.Value - 1;

                end

                app.CirclesSpinner.Value = saver;

            end

        end

        function runImageCorrection(app, ref_centers)

            counter = 1;

            error = 100;
            while max(abs(error)) > 5 && counter < 10

                [Centers, Radii, ~] = app.detectCircles;

                % close all

                % get error
                try
                    if(numel(Centers(:, 1)) > 1)
                        error = mean(ref_centers - Centers);
                    else
                        error = ref_centers - Centers;
                    end

                    % convert error to um
                    error_um = error / mean(Radii) * app.CirclesizeSpinner.Value;

                    % move stage
                    if app.flagStop
                        break
                    end
                    app.Stage.MoveX(-error_um(1 * app.UnitMult));
                    app.Stage.MoveY(error_um(2 * app.UnitMult));

                    % update counter
                    counter = counter + 1;
                end


                % dlg = warndlg('Move?');
                % waitfor(dlg);
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            close all
            clc

            % DetectedConfig = app.setStageController();
            % 
            % app.Stage = StageController(DetectedConfig);

            app.Stage = StageController;

            app.cameraSettings = camSettings;

            % Setup Timer for Camera
            app.CameraDAQTimer = timer('StartDelay', 0.1, 'Period', 0.1, ...
                'ExecutionMode', 'fixedSpacing', ...  % <--- MUST BE 'fixedSpacing'
                'TasksToExecute', Inf, ...
                'TimerFcn', @app.timerCameraDAQ);

            app.CameraPanel.Enable = "off";
            app.STARTCHIPButton.Enable = "on";
            app.STOPButton.Enable = "off";
            app.AbortButton.Enable = "off";

            app.flagPause = 0;
            app.flagStop = 0;

            % Try to start the camera safely
            try
                app.StartCamera();
            catch ME
                % Show a warning but don't crash the app
                fprintf('Camera Error: %s\n', ME.message);
                warndlg('Camera could not start. Check connection.', 'Camera Warning');
                app.CameraPanel.Enable = "on";
            end

            % Set ADwin and initialize routines
            try
                app.ADwinSettings.ADC = {1e7, 'off', 'off', 'off', 'off', 'off', 'off', 'off'};
                app.ADwinSettings.auto = 'FEMTO';
                app.ADwinSettings.ADC_gain = [0 0 0 0 0 0 0 0];
                app.ADwinSettings.ADwin = 'GoldII';
                app.ADwinSettings.res4p = 0;
                app.ADwinSettings.get_sample_T = '';
                app.ADwinSettings.T = 300;

                idx = regexp(pwd,'(Matlab\\)');
                tmp = pwd;
                addpath(genpath([tmp(1:idx-1) 'Matlab\Libs\']));
                app.ADwinSettings.path = [tmp(1:idx-1) 'Matlab\Libs\ADwin_script'];
                addpath(genpath(app.ADwinSettings.path));

                app.ADwinSettings = Init_ADwin_boot_only(app.ADwinSettings);

                app.ADwinSettings.save_dir = sprintf("%s\\%s", app.SaveDirectoryEditField.Value, app.SampleNameEditField.Value);
                app.ADwinSettings.sample = app.SampleNameEditField.Value;

                app.needleContact = needleRoutine(app.ADwinSettings);
                app.switchingBox = switchBox(app.ADwinSettings);
                app.makeIV = makeIVRoutine(app.ADwinSettings);
                app.makeGateSweep = makeGateSweepRoutine(app.ADwinSettings);
                app.makeStability = makeStabilityRoutine(app.ADwinSettings);

                fprintf('ADwin and measurement routines initialized.');
            catch ME
                fprintf(2, 'ADwin init failed: %s', ME.message);
                warndlg(sprintf('ADwin could not initialize: %s. Measurement routines unavailable.', ME.message), 'ADwin Warning');
            end
            end

        end

        % Callback function
        function ConnectMotor(app, event)
        end

        % Callback function
        function DisconnectMotor(app, event)
        end

        % Button pushed function: cRotButton
        function PosButtonPushed(app, event)
            app.Stage.MoveTheta(app.EditStepsRot.Value);
        end

        % Button pushed function: ccRotButton
        function NegButtonPushed(app, event)
            app.Stage.MoveTheta(-app.EditStepsRot.Value);
        end

        % Callback function
        function PositionEditFieldValueChanged(app, event)
        end

        % Button pushed function: STOPMOVEButton
        function STOPMOVEButtonPushed(app, event)
            app.Stage.Stop;
            app.flagStop = 1;
        end

        % Close request function: ESCOTestUIFigure
        function ESCOTestUIFigureCloseRequest(app, event)
            delete(app.Stage)
            delete(app)
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            app.ESCOTestUIFigureCloseRequest(app);
        end

        % Button pushed function: StartCameraButton
        function StartCamera(app, event)
            
            
            % 1. Cleanup
            if ~isempty(app.Vid)
                try closePreview(app.Vid); catch; end
                app.Vid = []; 
            end
            
            try
                % 2. Connect
                devID = 1;
                if ~isempty(app.cameraSettings) && isfield(app.cameraSettings.Video, 'DeviceID')
                    devID = app.cameraSettings.Video.DeviceID;
                end
                app.Vid = webcam(devID); 
                
                % 3. Resolution
                if ~isempty(app.cameraSettings) && isstruct(app.cameraSettings.Video)
                     targetRes = sprintf('%dx%d', app.cameraSettings.Video.VidResX, app.cameraSettings.Video.VidResY);
                     if ismember(targetRes, app.Vid.AvailableResolutions)
                        app.Vid.Resolution = targetRes;
                     end
                end

                % 4. FORCE COLOR & EXPOSURE (Retain our fixes)
                if isprop(app.Vid, 'ReturnedColorSpace')
                    app.Vid.ReturnedColorSpace = 'rgb';
                end
                try app.Vid.ExposureMode = 'manual'; catch; end
                try app.Vid.WhiteBalanceMode = 'auto'; catch; end
                
                % Sync Sliders
                try app.SliderExposure.Value = app.Vid.Exposure; catch; end
                try app.SliderBrightness.Value = app.Vid.Brightness; catch; end

                % 5. PREPARE UI (Fixes the "Too Small" issue)
                res = app.Vid.Resolution; 
                splitRes = sscanf(res, '%dx%d'); 
                W = splitRes(1); H = splitRes(2);
                
                cla(app.UIvideo);
                reset(app.UIvideo); 
                
                % Create Image placeholder
                app.hImage = image(app.UIvideo, zeros(H,W,3,'uint8'), 'HitTest', 'off');
                
                % Lock Axis limits tightly to the image to maximize size
                app.UIvideo.XLim = [0.5, W+0.5];
                app.UIvideo.YLim = [0.5, H+0.5];
                app.UIvideo.YDir = 'reverse';
                app.UIvideo.DataAspectRatio = [1 1 1];
                
                % Hide the ruler lines so the image can expand further
                app.UIvideo.XAxis.Visible = 'off';
                app.UIvideo.YAxis.Visible = 'off';
                
                % 6. START NATIVE PREVIEW (Fixes the Lag)
                preview(app.Vid, app.hImage);
                
                % 7. Overlays
                hold(app.UIvideo, "on"); 
                app.hLineV = line(app.UIvideo, [NaN NaN], [NaN NaN], 'Color', 'red', 'LineWidth', 1, 'HitTest', 'off', 'Visible', 'off');
                app.hLineH = line(app.UIvideo, [NaN NaN], [NaN NaN], 'Color', 'red', 'LineWidth', 1, 'HitTest', 'off', 'Visible', 'off');
                app.hCircle = rectangle(app.UIvideo, 'Position', [0 0 1 1], 'EdgeColor', 'red', 'Curvature', [1 1], 'HitTest', 'off', 'Visible', 'off');
                hold(app.UIvideo, "off");

                % 8. Start Timer (for Crosshairs only)
                if strcmp(app.CameraDAQTimer.Running, 'off')
                    start(app.CameraDAQTimer);
                end
                
                app.StartCameraButton.Enable = "off";
                app.StopCameraButton.Enable = "on";
                app.CrosshairPanel.Enable = "on";
                app.SettingsPanel.Enable = 'on';
                app.CameraPanel.Enable = "on";
                
            catch ME
                warndlg(['Error starting camera: ' ME.message], 'Camera Error');
                app.CameraPanel.Enable = "on";
            end

        end

        % Button pushed function: StopCameraButton
        function StopCamera(app, event)

            stop(app.CameraDAQTimer);
            
            % Close Preview and Release
            if ~isempty(app.Vid)
                try closePreview(app.Vid); catch; end
                app.Vid = [];
            end
            
            app.StartCameraButton.Enable = "on";
            app.StopCameraButton.Enable = "off";
            app.CrosshairPanel.Enable = "off";
            app.SettingsPanel.Enable = 'off';

        end

        % Button pushed function: upButton
        function MoveStageUp(app, event)
            app.Stage.MoveY(app.EditStepsMove.Value/1000);
        end

        % Button pushed function: downButton
        function MoveStageDown(app, event)
            app.Stage.MoveY(-app.EditStepsMove.Value/1000);
        end

        % Button pushed function: rightButton
        function MoveStageRight(app, event)
            app.Stage.MoveX(-app.EditStepsMove.Value/1000);
        end

        % Button pushed function: leftButton
        function MoveStageLeft(app, event)
            app.Stage.MoveX(app.EditStepsMove.Value/1000);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.Stage.MoveZ(app.EditStepsNeedle.Value);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.Stage.MoveZ(-app.EditStepsNeedle.Value);
        end

        % Value changed function: VelocityXYEditField
        function VelocityXYEditFieldValueChanged(app, event)
            value = app.VelocityXYEditField.Value;
            app.Stage.MotorX.set_setting('velocity',      value);
            app.Stage.MotorY.set_setting('velocity',      value);
            % app.UITableStage.Data = {value, app.Stage.getFrequency};
        end

        % Value changed function: VelocityZEditField
        function VelocityZEditFieldValueChanged(app, event)
            value = app.VelocityZEditField.Value;
            app.Stage.MotorZ.set_setting('velocity',      value);            
        end

        % Callback function
        function AccelerationEditFieldValueChanged(app, event)
            value = app.AccelerationEditField.Value;
            app.Stage.setFrequency(value);
            % app.UITableStage.Data = {app.Motor.getAmplitude, value};
        end

        % Button pushed function: padUpButton
        function padUpButtonPushed(app, event)
            app.Stage.MoveY(app.EditStepsPad.Value * app.Layout{app.currentLayout}.getHeight * app.UnitMult);
        end

        % Button pushed function: padDownButton
        function padDownButtonPushed(app, event)
            app.Stage.MoveY(-app.EditStepsPad.Value * app.Layout{app.currentLayout}.getHeight * app.UnitMult);
        end

        % Button pushed function: padRightButton
        function padRightButtonPushed(app, event)
            app.Stage.MoveX(app.EditStepsPad.Value * app.Layout{app.currentLayout}.getWidth * app.UnitMult);
        end

        % Button pushed function: padLeftButton
        function padLeftButtonPushed(app, event)
            app.Stage.MoveX(-app.EditStepsPad.Value * app.Layout{app.currentLayout}.getWidth * app.UnitMult);
        end

        % Button pushed function: groupUpButton
        function groupUpButtonPushed(app, event)
            app.Stage.MoveY(app.EditStepsGroup.Value * app.Layout{app.currentLayout}.getRows * app.Layout{app.currentLayout}.getHeight + app.Layout{app.currentLayout}.getyLine * app.UnitMult);
        end

        % Button pushed function: groupDownButton
        function groupDownButtonPushed(app, event)
            app.Stage.MoveY(-1*(app.EditStepsGroup.Value * app.Layout{app.currentLayout}.getRows * app.Layout{app.currentLayout}.getHeight + app.Layout{app.currentLayout}.getyLine * app.UnitMult));
        end

        % Button pushed function: groupRightButton
        function groupRightButtonPushed(app, event)
            app.Stage.MoveX(app.EditStepsGroup.Value * app.Layout{app.currentLayout}.getColumns * app.Layout{app.currentLayout}.getWidth + app.Layout{app.currentLayout}.getxLine * app.UnitMult);
        end

        % Button pushed function: groupLeftButton
        function groupLeftButtonPushed(app, event)
            app.Stage.MoveX(-1*(app.EditStepsGroup.Value * app.Layout{app.currentLayout}.getColumns * app.Layout{app.currentLayout}.getWidth + app.Layout{app.currentLayout}.getxLine * app.UnitMult));
        end

        % Callback function
        function DeviceWidthEditFieldValueChanged(app, event)
            value = app.DeviceWidthEditField.Value;
            app.Layout{app.currentLayout}.setWidth(value);
            app.UITableDevice.Data{1} = value;
        end

        % Callback function
        function DeviceHeightEditFieldValueChanged(app, event)
            value = app.DeviceHeightEditField.Value;
            app.Layout{app.currentLayout}.setHeight(value);
            app.UITableDevice.Data{2} = value;
        end

        % Callback function
        function DevicesinaRowEditFieldValueChanged(app, event)
            value = app.DevicesinaRowEditField.Value;
            app.Layout{app.currentLayout}.setRows(value);
            app.UITableDevice.Data{3} = value;
        end

        % Callback function
        function DevicesinaColumnEditFieldValueChanged(app, event)
            value = app.DevicesinaColumnEditField.Value;
            app.Layout{app.currentLayout}.setColumns(value);
            app.UITableDevice.Data{4} = value;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            charValue = app.DropDown.Value;
            numValue = double(charValue) - double('0');

            if ~numValue
                app.getCustomCoordinatesFromFile;
            else
                app.currentLayout = numValue;
                app.generateDeviceCoordinates;
            end

            app.CellcolumnsEditField.Value = app.Layout{app.currentLayout}.getxCells;
            app.CellrowsEditField.Value = app.Layout{app.currentLayout}.getyCells;

            if app.currentLayout == 1

                app.MinRadiuspxSpinner.Value = 15;
                app.MaxRadiuspxSpinner.Value = 20;
                app.CirclesizeSpinner.Value = 50;

            elseif app.currentLayout == 2

                app.MinRadiuspxSpinner.Value = 6;
                app.MaxRadiuspxSpinner.Value = 10;
                app.CirclesizeSpinner.Value = 25;

            end

            % consoleLine = sprintf("%i", app.currentLayout);
            % fprintf("%i\n", app.currentLayout);
            % app.UITableDevice.Data = {app.Layout{app.currentLayout}.getWidth, app.Layout{app.currentLayout}.getHeight, app.Layout{app.currentLayout}.getRows, app.Layout{app.currentLayout}.getColumns, app.Layout{app.currentLayout}.getxCells, app.Layout{app.currentLayout}.getyCells};
        end

        % Value changed function: SliderBrightness
        function SliderBrightnessValueChanged(app, event)
            if ~isempty(app.Vid)
                try
                    app.Vid.Brightness = app.SliderBrightness.Value;
                catch
                    % Fail silently or print to console if camera doesn't support it
                    disp('Brightness property not supported by this camera.');
                end
            end
        end

        % Value changed function: SliderExposure
        function SliderExposureValueChanged(app, event)
            if ~isempty(app.Vid)
                try
                    % Webcam exposure is often 'Exposure', but sometimes requires setting
                    % 'ExposureMode' to 'manual' first.
                    app.Vid.Exposure = app.SliderExposure.Value;
                catch
                    disp('Exposure property not supported by this camera.');
                end
            end
        end

        % Callback function
        function AlignmentSwitchValueChanged(app, event)
            switch app.AlignmentSwitch.Value
                case 'On'
                    app.AlignmentLamp.Color = 'green';
                    % [clickX, clickY] = ginput();
                    % hold(app.UIvideo, "on");
                    % plot(app.UIvideo, clickX, clickY, 'r*');
                    % hold(app.UIvideo, "off");
                case 'Off'
                    app.AlignmentLamp.Color = 'red';
            end
        end

        % Button pushed function: STARTCHIPButton
        function STARTCHIPButtonPushed(app, event)
            app.flagStop = 0;
            app.flagPause = 0;
            app.STARTCHIPButton.Enable = "off";
            app.STARTDEVICEButton.Enable = "off";
            app.STOPButton.Enable = "on";

            app.measureChip();

            app.STARTCHIPButton.Enable = "on";
            app.STARTDEVICEButton.Enable = "on";
            app.STOPButton.Enable = "off";

        end

        % Callback function
        function STARTCELLButtonPushed(app, event)
            app.flagStop = 0;
            app.flagPause = 0;
            app.STARTCELLButton.Enable = "off";
            app.STARTCHIPButton.Enable = "off";
            app.STARTDEVICEButton.Enable = "off";
            app.STOPButton.Enable = "on";

            app.measureCell();

            app.STARTCELLButton.Enable = "on";
            app.STARTCHIPButton.Enable = "on";
            app.STARTDEVICEButton.Enable = "on";
            app.STOPButton.Enable = "off";

        end

        % Button pushed function: STARTDEVICEButton
        function STARTDEVICEButtonPushed(app, event)
            app.flagStop = 0;
            app.flagPause = 0;
            app.STARTCHIPButton.Enable = "off";
            app.STARTDEVICEButton.Enable = "off";
            app.STOPButton.Enable = "on";

            mydlg = warndlg({'Put Switchbox on Yellow!!'}, 'Warning');
            waitfor(mydlg);

            % app.Motor.move_leftProbe(100); % Move needles back up before moving
            app.LLamp.Color = 'red';
            % app.Motor.move_rightProbe(100);
            app.RLamp.Color = 'red';

            contactFlag = app.contactFunction('Left');
            app.switchingBox.startRoutine;
            pause(2);
            if contactFlag
                contactFlag = app.contactFunction('Right');
            end
            app.switchingBox.startRoutine;

            % Add loop that iterates over the steps in the listbox
            pause(2)
            if contactFlag
                app.executeRoutines(app.ADwinSettings.save_dir, app.DeviceNrEditField.Value);
            else
                warndlg({'Contact failed'}, 'Warning');
            end

            app.Stage.MoveZ(50 * app.UnitMult); % Move needles back up before moving
            app.LLamp.Color = 'red';
            app.Stage.MoveZ(50 * app.UnitMult);
            app.RLamp.Color = 'red';

            app.STARTCHIPButton.Enable = "on";
            app.STARTDEVICEButton.Enable = "on";
            app.STOPButton.Enable = "off";

        end

        % Button pushed function: ClearListButton
        function ClearListButtonPushed(app, event)
            app.ListBox.Items = {};
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)

            if strcmp(app.PAUSEButton.Text, 'PAUSE')
                app.flagPause = 1;
            else
                app.flagPause = 0;
            end

            if strcmp(app.PAUSEButton.Text, 'PAUSE')
                app.PAUSEButton.Text = 'RESUME';
            else
                app.PAUSEButton.Text = 'PAUSE';
            end

        end

        % Button pushed function: ContactButton
        function ContactButtonPushed(app, event)
            app.flagPause = 0;
            app.flagStop = 0;


            app.ContactButton.Enable = "off";
            app.AbortButton.Enable = "on";

            needle = strsplit(app.DropDown_needleSide.Value, ' ');
            contactFlag = app.contactFunction(needle{1}, app.NeedlePlot);

            if contactFlag
                switch needle{1}

                    case 'Left'
                        app.LLamp.Color = 'green';

                    case 'Right'
                        app.RLamp.Color = 'green';

                end

            else

                switch needle{1}

                    case 'Left'
                        app.LLamp.Color = 'red';
                        app.Stage.MoveZ(100 * app.UnitMult); % Move needles back up before moving

                    case 'Right'
                        app.RLamp.Color = 'red';
                        app.Stage.MoveZ(100 * app.UnitMult);
                end

            end

            app.ContactButton.Enable = "on";
            app.AbortButton.Enable = "off";
            hold(app.NeedlePlot, "off");

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            value = app.OperationsSelection.Value;

            if ~(strcmp(value, '<Select Operation>'))
                app.ListBox.Items{end + 1} = value;
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.flagStop = 1;
            app.STARTCHIPButton.Enable = "on";
            app.STARTDEVICEButton.Enable = "on";
            app.STOPButton.Enable = "off";


            %% Stopping individual Routines
            app.makeStability.stopFlag = 1;

            for i=1:10
                Stop_Process(i);
            end
        end

        % Value changed function: DropDown_needleSide
        function DropDown_needleSideValueChanged(app, event)
            app.switchingBox.startRoutine;
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.flagStop = 1;

            for i=1:10
                Stop_Process(i);
            end


            app.ContactButton.Enable = "on";
            app.AbortButton.Enable = "off";
        end

        % Value changed function: CellcolumnsEditField
        function CellcolumnsEditFieldValueChanged(app, event)
            app.Layout{app.currentLayout}.setxCells(app.CellcolumnsEditField.Value);
            app.generateDeviceCoordinates;
        end

        % Value changed function: CellrowsEditField
        function CellrowsEditFieldValueChanged(app, event)
            app.Layout{app.currentLayout}.setyCells(app.CellrowsEditField.Value);
            app.generateDeviceCoordinates;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file, path] = uiputfile({'*.jpg', 'JPEG Image (*.jpg)'; '*.png', 'PNG Image (*.png)'}, 'Save Image As');

            app.captureImage(file, path);

            % Check if the user clicked 'Cancel'

        end

        % Button pushed function: TrigSwitchBoxButton
        function TrigSwitchBoxButtonPushed(app, event)
            app.switchingBox.startRoutine;
        end

        % Value changed function: SaveDirectoryEditField
        function SaveDirectoryEditFieldValueChanged(app, event)
            app.ADwinSettings.save_dir = sprintf("%s\\%s", app.SaveDirectoryEditField.Value, app.SampleNameEditField.Value);

        end

        % Value changed function: SampleNameEditField
        function SampleNameEditFieldValueChanged(app, event)
            app.failedDevices = 0;
            app.ADwinSettings.sample = app.SampleNameEditField.Value;
            app.ADwinSettings.save_dir = sprintf("%s\\%s", app.SaveDirectoryEditField.Value, app.SampleNameEditField.Value);
            % Specify the name of the new directory in the current working directory

        end

        % Callback function
        function ContactThresholdAEditFieldValueChanged(app, event)
            app.needleContact.threshold = app.ContactThresholdAEditField.Value;

        end

        % Button pushed function: Button_findCircles
        function Button_findCirclesPushed(app, event)

            [Centers, Radii, Image_data_red] = app.detectCircles;
            figure; hold on;
            imshow(Image_data_red)
            viscircles(Centers, Radii,'EdgeColor','b');

        end

        % Button pushed function: Button_CaptureReference
        function Button_CaptureReferencePushed(app, event)

            [app.tmp_ref_centers, app.tmp_ref_radii] = app.captureReference;

        end

        % Button pushed function: Button_Run_correction
        function Button_Run_correctionPushed(app, event)

            app.runImageCorrection(app.tmp_ref_centers);

        end

        % Button pushed function: Button_Align_rotation
        function Button_Align_rotationPushed(app, event)

            app.flagStop = 0;

            groupMove_x = app.Layout{app.currentLayout}.getColumns * app.Layout{app.currentLayout}.getWidth + app.Layout{app.currentLayout}.getxLine;
            range = app.RangeSpinner.Value;
            step = app.StepSpinner.Value;

            rotRange = -range:step:range;
            rotSteps = [-range step*ones(1, numel(rotRange) - 1)];
            error_y = inf(numel(rotSteps), 1);  % Initialize with large values
            % Capture initial position to readjust every time and improve
            % stability
            app.Button_CaptureReferencePushed;

            for counter = 1:numel(rotSteps)
                % Move the stage by the current rotation step
                app.Stage.MoveTheta(rotSteps(counter));

                % Capture this reference
                [ref_centers, ~] = app.detectCircles;

                if app.flagStop
                    break
                end

                app.Stage.MoveX(groupMove_x * app.UnitMult);

                [Centers, Radii, ~] = app.detectCircles;

                % Calculate the error
                error = mean(ref_centers - Centers);

                % Convert error to micrometers
                error_um = error / mean(Radii) * app.CirclesizeSpinner.Value;
                error_y(counter) = abs(error_um(2));

                % Log the error for debugging
                fprintf('Rotation step %d: error = %f um\n', rotRange(counter), error_y(counter));

                app.Stage.MoveX(-groupMove_x * app.UnitMult);
                app.Button_Run_correctionPushed;
            end

            if ~app.flagStop
                % Find the rotation step with the minimum error
                [~, minIndex] = min(error_y);
                % Move the stage to the optimal rotation angle
                app.Stage.MoveTheta((numel(rotSteps) - minIndex)*(-step));

                warndlg('Rotation adjusted!', 'Warning');
            else
                warndlg('Rotation not adjusted!', 'Warning');
            end


        end

        % Button pushed function: GenerateSpreadsheetButton
        function GenerateSpreadsheetButtonPushed(app, event)

            % Function to process yield data
            function devArray = processYield(folderPath, sampleName)
                % Get a list of all .mat files in the specified folder
                filePattern = fullfile(folderPath, ['*' sampleName '-*.mat']);
                files = dir(filePattern);
                numFiles = length(files);

                % Initialize the gapOpening array
                devArray = ones(1, app.CellcolumnsEditField.Value * app.CellrowsEditField.Value * app.Layout{app.currentLayout}.getRows() * app.Layout{app.currentLayout}.getColumns());
                selected_voltage = 0.5;
                % Loop over each file
                for k = 1:numFiles
                    fileName = files(k).name;

                    % Extract the sample number from the file name
                    tokens = regexp(fileName, [sampleName '-(\d+)_IV'], 'tokens');
                    % Check if filename is correctly formatted
                    if isempty(tokens)
                        continue; % Skip this iteration if tokens is empty
                    end
                    sampleNumber = str2double(tokens{1}{1});

                    % Load the .mat file
                    data = load(fullfile(folderPath, fileName));

                    % Access the 'IV' class and the 'current' cell array
                    if isfield(data, 'IV') && isfield(data.IV, 'current')
                        currentCellArray = data.IV.current;

                        % Perform Desired Calculation
                        computedValues = ones(size(currentCellArray));
                        for j = 1:numel(currentCellArray)

                            % Load the data
                            bias_voltage = data.IV.bias;
                            current = currentCellArray{j};

                            switch app.spreadsheetDropDown.Value

                                case 'Gap Opening'

                                    if mean(abs(currentCellArray{j})) > 1e-11
                                        computedValues(j) = 0;
                                    end

                                case 'Linearized - Resistance'

                                    % Calculate the derivative of the current with respect to bias voltage
                                    dI_dV = diff(current) ./ diff(bias_voltage);

                                    % Filter out infinite and NaN values in the derivative
                                    valid_indices = isfinite(dI_dV);
                                    filtered_dI_dV = dI_dV(valid_indices);

                                    % Smooth the derivative to reduce noise
                                    avg_dI_dV = movmean(filtered_dI_dV, 5);

                                    % Identify the region where the derivative is approximately constant
                                    threshold = std(avg_dI_dV) * 0.1;
                                    linear_region = abs(avg_dI_dV - mean(avg_dI_dV)) < threshold;

                                    linear_bias_voltage = bias_voltage(linear_region);
                                    linear_current = current(linear_region);

                                    % Perform linear regression on the linear region
                                    p = polyfit(linear_bias_voltage, linear_current, 1);
                                    slope = p(1);

                                    % Calculate the resistance
                                    computedValues(j) = abs( 1 / slope );

                                case 'Resistance - 04V'

                                    % Define the linearization region
                                    linearization_setpoint = 0.4;
                                    linearization_threshold = 0.05;

                                    % Select the data points in the linearization region
                                    linearization_indices = (linearization_setpoint - linearization_threshold < bias_voltage) & (bias_voltage < linearization_setpoint + linearization_threshold);

                                    linearization_voltage = bias_voltage(linearization_indices);
                                    linearization_current = current(linearization_indices);

                                    p = polyfit(linearization_voltage, linearization_current, 1); % p(1) is the slope, p(2) is the intercept

                                    % Calculate resistance as the inverse of the slope
                                    computedValues(j) =abs( 1 / p(1) );

                                case 'Low Bias - Resistance'

                                    % Define the low-bias region threshold
                                    low_bias_threshold = 0.1; % Adjust this value based on your data

                                    % Select the data points in the low-bias region
                                    low_bias_indices = abs(bias_voltage) < low_bias_threshold;
                                    low_bias_voltage = bias_voltage(low_bias_indices);
                                    low_bias_current = current(low_bias_indices);

                                    % Perform linear regression on the low-bias region
                                    p = polyfit(low_bias_voltage, low_bias_current, 1); % p(1) is the slope, p(2) is the intercept

                                    % Calculate resistance as the inverse of the slope
                                    computedValues(j) =abs( 1 / p(1) );

                                case 'Linearity'

                                    % Fit a linear regression model
                                    p = polyfit(bias_voltage, current, 1);
                                    lin_current = polyval(p, bias_voltage);

                                    % Calculate residuals
                                    residuals = current - lin_current;

                                    % Calculate the sum of squared residuals
                                    ss_res = sum(residuals.^2);

                                    % Calculate the total sum of squares (total variability in the data)
                                    ss_tot = sum((current - mean(current)).^2);

                                    % Calculate R-squared
                                    R_squared = 1 - (ss_res / ss_tot);

                                    computedValues(j) = R_squared;

                                case 'Current'

                                    % Find indices of the specific voltage in the voltage vector
                                    indices = find(bias_voltage == selected_voltage);

                                    if ~isempty(indices)
                                        % If the specific voltage is found, calculate the average current
                                        computedValues(j) = mean(current(indices));
                                    else
                                        % If the specific voltage is not found, find the closest voltage
                                        [~, closestIndex] = min(abs(bias_voltage - selected_voltage));
                                        computedValues = current(closestIndex);
                                    end

                            end % switch

                        end % for j

                        % Store results
                        devArray(sampleNumber) = computedValues;
                    else
                        warning('IV class or current array not found in %s', fileName);
                    end % if
                end % for k
            end % function


            % Helper function to convert column numbers to Excel column letters
            function letter = columnLetter(columnNumber)
                letter = '';
                while columnNumber > 0
                    remainder = mod(columnNumber - 1, 26);
                    letter = [char(65 + remainder) letter];
                    columnNumber = floor((columnNumber - remainder - 1) / 26);
                end
            end

            % Define the necessary variables (Adjust these as needed)
            folderPath = app.ADwinSettings.save_dir; % Replace with the correct folder path
            sampleName = app.ADwinSettings.sample; % Replace with the correct sample name

            % % Load the gapOpening data
            devArray = processYield(folderPath, sampleName);

            % Initialize the 3D cell array based on the layout
            Cellcolumns = app.CellcolumnsEditField.Value;
            Cellrows = app.CellrowsEditField.Value;
            numRows = app.Layout{app.currentLayout}.getRows();
            numCols = app.Layout{app.currentLayout}.getColumns();

            devMatrix3D = ones(Cellrows * numRows, Cellcolumns * numCols);
            devNumbers3D = zeros(Cellrows * numRows, Cellcolumns * numCols);

            flag = 0;
            n = 1;

            for kY = 1:Cellcolumns
                for kX = 1:Cellrows
                    if flag
                        break;
                    end
                    for colIndex = 1:numCols
                        if flag
                            break;
                        end
                        for rowIndex = 1:numRows
                            if n <= numel(devArray)
                                devMatrix3D((kX - 1) * numRows + rowIndex, (kY - 1) * numCols + colIndex) = devArray(n);
                                devNumbers3D((kX - 1) * numRows + rowIndex, (kY - 1) * numCols + colIndex) = n;
                                n = n + 1;
                            else
                                flag = 1;
                                break;
                            end
                        end
                    end
                end
            end

            % Save the 3D cell array
            matname = sprintf('%s-%s.mat', app.ADwinSettings.sample, app.spreadsheetDropDown.Value);
            matname = fullfile(folderPath, matname);
            save(matname, "devMatrix3D");

            % Write to Excel file
            filename = sprintf('%s-%s.xlsx', app.ADwinSettings.sample, app.spreadsheetDropDown.Value);
            filename = fullfile(folderPath, filename);


            % Initialize an empty cell array to store the logical values for the entire layout
            fullLayout = devMatrix3D'; % Note the swapped dimensions

            switch app.spreadsheetDropDown.Value

                case 'Gap Opening'

                    try

                        excelData = devNumbers3D';
                        writematrix(excelData, filename);
                        excel = actxserver('Excel.Application');
                        workbook = excel.Workbooks.Open(filename);
                        sheet = workbook.Sheets.Item(1);

                        % Define the color codes
                        greenColor = hex2dec('00FF00'); % Hex for green
                        redColor = hex2dec('0000FF');   % Hex for red

                        % Apply formatting to each cell
                        for row = 1:size(excelData, 1)

                            for col = 1:size(excelData, 2)

                                cellAddress = sprintf('%s%d', columnLetter(col), row);
                                cell = sheet.Range(cellAddress);

                                if fullLayout(row, col) == true

                                    cell.Interior.Color = greenColor;

                                else

                                    cell.Interior.Color = redColor;

                                end

                            end

                        end

                        % % Force a screen update
                        % excel.ScreenUpdating = true;
                        % excel.DisplayAlerts = false;

                        % Save and close the workbook
                        workbook.Save();
                        workbook.Close(false);
                        excel.Quit();

                        % Clean up
                        delete(excel);

                    catch ME
                        % Display any errors that occur
                        disp('Error applying conditional formatting to Excel cells:');
                        disp(ME.message);
                        % Ensure Excel process is quit in case of error
                        if exist('excel', 'var')

                            excel.Quit();
                            delete(excel);

                        end

                    end

                otherwise

                    excelData = fullLayout;
                    writematrix(excelData, filename);

            end

            warndlg('Spreadsheet generated!', 'Success!');

        end

        % Value changed function: StepfactorXdirectionEditField
        function StepfactorXdirectionEditFieldValueChanged(app, event)
            app.Stage.stepFactorX = app.StepfactorXdirectionEditField.Value;

        end

        % Value changed function: StepfactorYdirectionEditField
        function StepfactorYdirectionEditFieldValueChanged(app, event)
            app.Stage.stepFactorY = app.StepfactorYdirectionEditField.Value;

        end

        % Value changed function: HysthfactorXdirectionEditField
        function HysthfactorXdirectionEditFieldValueChanged(app, event)
            app.Stage.corrFactorX = app.HysthfactorXdirectionEditField.Value;

        end

        % Value changed function: HysthfactorYdirectionEditField
        function HysthfactorYdirectionEditFieldValueChanged(app, event)
            app.Stage.corrFactorY = app.HysthfactorYdirectionEditField.Value;

        end

        % Value changed function: MotionSwitch
        function TestMotiononlySwitchValueChanged(app, event)
            value = app.MotionSwitch.Value;
            if strcmp(value, 'On')
                app.testingMovement = 1;
            else
                app.testingMovement = 0;
            end

        end

        % Button pushed function: INITIALIZESTAGEButton
        function INITIALIZESTAGEButtonPushed(app, event)
            mydlg = warndlg('Position on top-left device', 'Warning');
            waitfor(mydlg);

            app.flagStop =  0;

            while ~app.flagStop

                % app.Motor.stepFactorX = 0.5;
                % app.Motor.stepFactorY = 0.5;
                % app.Motor.corrFactorX = 1;
                % app.Motor.corrFactorY = 1;

                app.Button_CaptureReferencePushed;
                % =================================================================
                % Adjust stepFactorX
                % =================================================================
                steps = app.Layout{app.currentLayout}.getColumns * app.Layout{app.currentLayout}.getWidth + app.Layout{app.currentLayout}.getxLine;

                if app.flagStop
                    app.restoreFactorsFromUI();
                    break
                end

                app.Stage.MoveX(steps * app.UnitMult);

                [Centers, Radii, ~] = app.detectCircles;

                if app.flagStop
                    app.restoreFactorsFromUI();
                    break
                end

                % get error
                try

                    error = mean(app.tmp_ref_centers - Centers);

                    % convert error to um
                    error_um = error / mean(Radii) * app.CirclesizeSpinner.Value;
                    deltaSteps = error_um(1);
                    correction = (steps - deltaSteps)/steps;

                    app.Stage.stepFactorX = app.Stage.stepFactorX*correction;
                catch
                    mydlg = warndlg('stepFactorX not updated', 'Warning');
                    waitfor(mydlg);

                end

                app.StepfactorXdirectionEditField.Value = app.Stage.stepFactorX;
                app.Button_Run_correctionPushed;
                % =================================================================
                % Adjust corrFactorX
                % =================================================================
                steps = -steps;
                if app.flagStop
                    app.restoreFactorsFromUI();
                    break
                end
                app.Stage.MoveX(steps * app.UnitMult);

                [Centers, Radii, ~] = app.detectCircles;

                if app.flagStop
                    app.restoreFactorsFromUI();
                    break
                end

                % get error
                try

                    error = mean(app.tmp_ref_centers - Centers);

                    % convert error to um
                    error_um = error / mean(Radii) * app.CirclesizeSpinner.Value;
                    deltaSteps = error_um(1);
                    correction = (steps - deltaSteps)/steps;
                    app.Stage.corrFactorX = app.Stage.corrFactorX*correction;

                catch
                    mydlg = warndlg('corrFactorX not updated', 'Warning');
                    waitfor(mydlg);

                end

                app.HysthfactorXdirectionEditField.Value = app.Stage.corrFactorX;
                app.Button_Run_correctionPushed;
                % =================================================================
                % Adjust stepFactorY
                % =================================================================
                steps = (-1)*(app.Layout{app.currentLayout}.getRows * app.Layout{app.currentLayout}.getHeight + app.Layout{app.currentLayout}.getyLine);
                if app.currentLayout == 1
                    extraStep = (-1) * app.Layout{app.currentLayout}.getHeight;
                elseif app.currentLayout == 2
                    extraStep = (-1) * 200;
                end

                if app.flagStop
                    app.restoreFactorsFromUI();
                    break
                end

                app.Stage.MoveY(extraStep * app.UnitMult);

                app.Button_CaptureReferencePushed;

                app.Stage.MoveY(steps * app.UnitMult);

                [Centers, Radii, ~] = app.detectCircles;

                if app.flagStop
                    app.restoreFactorsFromUI();
                    break
                end

                % get error
                try

                    error = mean(app.tmp_ref_centers - Centers);

                    % convert error to um
                    error_um = error / mean(Radii) * app.CirclesizeSpinner.Value;
                    deltaSteps = error_um(2);
                    correction = (steps + deltaSteps)/(steps);

                    app.Stage.stepFactorY = app.Stage.stepFactorY*correction;
                catch
                    mydlg = warndlg('stepFactorY not updated', 'Warning');
                    waitfor(mydlg);

                end

                app.StepfactorYdirectionEditField.Value = app.Stage.stepFactorY;
                app.Button_Run_correctionPushed;
                % =================================================================
                % Adjust corrFactorY
                % =================================================================
                steps = -steps;

                if app.flagStop
                    app.restoreFactorsFromUI();
                    break
                end

                app.Stage.MoveY(steps * app.UnitMult);

                [Centers, Radii, ~] = app.detectCircles;

                if app.flagStop
                    app.restoreFactorsFromUI();
                    break
                end

                % get error
                try

                    error = mean(app.tmp_ref_centers - Centers);

                    % convert error to um
                    error_um = error / mean(Radii) * app.CirclesizeSpinner.Value;
                    deltaSteps = error_um(2);
                    correction = (steps + deltaSteps)/(steps);

                    app.Stage.corrFactorY = app.Stage.corrFactorY*correction;

                catch
                    mydlg = warndlg('corrFactorY not updated', 'Warning');
                    waitfor(mydlg);

                end

                app.HysthfactorYdirectionEditField.Value = app.Stage.corrFactorY;
                app.Button_Run_correctionPushed;

                % =================================================================
                % Adjust Rotation
                % =================================================================
                if strcmp(app.RotationSwitch.Value, 'Yes')
                    app.Button_Align_rotationPushed;
                end

                app.flagStop = 1;

                warndlg('Initialization done!', 'Success');

            end

            if ~app.flagStop

                warndlg('Initialization aborted!', 'Warning');

            end

        end


        % Helper: restore stage calibration factors from UI fields
        function restoreFactorsFromUI(app)
            app.Stage.stepFactorX = app.StepfactorXdirectionEditField.Value;
            app.Stage.stepFactorY = app.StepfactorYdirectionEditField.Value;
            app.Stage.corrFactorX = app.HysthfactorXdirectionEditField.Value;
            app.Stage.corrFactorY = app.HysthfactorYdirectionEditField.Value;
        end

        % Button pushed function: ABORTINITIALIZATIONButton
        function ABORTINITIALIZATIONButtonPushed(app, event)
            app.flagStop = 1;

        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            app.needleContact.threshold = app.ThresholdEditField.Value;

        end

        % Value changed function: RuntimesEditField
        function RuntimesEditFieldValueChanged(app, event)
            app.needleContact.runtime = app.RuntimesEditField.Value;

        end

        % Value changed function: VperVEditField
        function VperVEditFieldValueChanged(app, event)
            app.makeIV.IV.V_per_V = app.VperVEditField.Value;

        end

        % Value changed function: StartVVEditField
        function StartVVEditFieldValueChanged(app, event)
            app.makeIV.IV.startV = app.StartVVEditField.Value;

        end

        % Value changed function: MaxVVEditField
        function MaxVVEditFieldValueChanged(app, event)
            app.makeIV.IV.maxV = app.MaxVVEditField.Value;

        end

        % Value changed function: MinVVEditField
        function MinVVEditFieldValueChanged(app, event)
            app.makeIV.IV.minV = app.MinVVEditField.Value;

        end

        % Value changed function: PointsEditField
        function PointsEditFieldValueChanged(app, event)
            app.makeIV.IV.points = app.PointsEditField.Value;

        end

        % Value changed function: DirectionEditField
        function DirectionEditFieldValueChanged(app, event)
            app.makeIV.IV.sweep_dir = app.DirectionEditField.Value;

        end

        % Value changed function: RepeatsEditField
        function RepeatsEditFieldValueChanged(app, event)
            app.makeIV.IV.repeat = app.RepeatsEditField.Value;

        end

        % Value changed function: MaxIAEditField
        function MaxIAEditFieldValueChanged(app, event)
            app.makeIV.IV.maxI = app.MaxIAEditField.Value;

        end

        % Value changed function: RateHzEditField
        function RateHzEditFieldValueChanged(app, event)
            app.makeIV.IV.scanrate = app.RateHzEditField.Value;

        end

        % Value changed function: SettlingTimemsEditField
        function SettlingTimemsEditFieldValueChanged(app, event)
            app.makeIV.IV.settling_time = app.SettlingTimemsEditField.Value;

        end

        % Value changed function: SettlingTAutorangemsEditField
        function SettlingTAutorangemsEditFieldValueChanged(app, event)
            app.makeIV.IV.settling_time_autoranging = app.SettlingTAutorangemsEditField.Value;

        end

        % Value changed function: InitVVEditField
        function InitVVEditFieldValueChanged(app, event)
            app.makeIV.Gate.initV = app.InitVVEditField.Value;

        end

        % Value changed function: TargetVVEditField
        function TargetVVEditFieldValueChanged(app, event)
            app.makeIV.Gate.targetV = app.TargetVVEditField.Value;

        end

        % Value changed function: EndVVEditField
        function EndVVEditFieldValueChanged(app, event)
            app.makeIV.Gate.endV = app.EndVVEditField.Value;

        end

        % Value changed function: VperVEditField_2
        function VperVEditField_2ValueChanged(app, event)
            app.makeIV.Gate.V_per_V = app.VperVEditField_2.Value;

        end

        % Value changed function: WaitingsEditField
        function WaitingsEditFieldValueChanged(app, event)
            app.makeIV.Gate.waiting_time = app.WaitingsEditField.Value;

        end

        % Value changed function: RampVsEditField
        function RampVsEditFieldValueChanged(app, event)
            app.makeIV.Gate.ramp_rate = app.RampVsEditField.Value;

        end

        % Value changed function: TemperatureEditField
        function TemperatureEditFieldValueChanged(app, event)
            app.ADwinSettings.T = app.TemperatureEditField.Value;

        end

        % Value changed function: BW_corrEditField
        function BW_corrEditFieldValueChanged(app, event)
            app.makeIV.IV.BW_adjuster = app.BW_corrEditField.Value;

        end

        % Value changed function: CaptureSwitch
        function CaptureSwitchValueChanged(app, event)

            value = app.CaptureSwitch.Value;
            if strcmp(value, 'On')
                app.isRef_needed = 1;
            else
                app.isRef_needed = 0;
            end

        end

        % Button pushed function: GenerateHysthogramButton
        function GenerateHysthogramButtonPushed(app, event)
                        filename = sprintf('%s-%s.xlsx', app.ADwinSettings.sample, app.spreadsheetDropDown.Value);
            filename = fullfile(app.ADwinSettings.save_dir, filename);
            % Read the resistance data from the Excel file
            data = readmatrix(filename);

            % Read the color data from the Excel file (using a method to extract colors)
            gapOpeningName = sprintf('%s-Gap Opening.xlsx', app.ADwinSettings.sample);
            gapOpeningName = fullfile(app.ADwinSettings.save_dir, gapOpeningName);
            [~, ~, raw] = xlsread(gapOpeningName);

            % Initialize a logical mask to filter out green cells
            green_mask = false(size(data));

            % Check each cell for green color (this example assumes that green cells
            % have specific RGB values in Excel)
            for i = 1:size(raw, 1)
                for j = 1:size(raw, 2)
                    if iscell(raw{i, j}) && ischar(raw{i, j})
                        % Check if the cell's string includes the color 'green'
                        if contains(lower(raw{i, j}), 'green')
                            green_mask(i, j) = true;
                        end
                    end
                end
            end

            % Flatten the matrix and remove NaN values and green cells
            values = data(~isnan(data(:)) & ~green_mask(:));

            % Determine the orders of magnitude in the dataset
            min_order = floor(log10(min(values)));
            max_order = ceil(log10(max(values)));

            % Calculate the number of bins
            num_bins = max_order - min_order + 1;

            if num_bins < 5
                % If less than 5 orders of magnitude, split some bins within the same order
                bin_edges = linspace(min_order, max_order, 5 + 1);
                bins = 10.^bin_edges;
            else
                % Use one bin per order of magnitude
                bins = 10 .^ (min_order:max_order);
            end

            % Create the histogram
            figure;
            histogram(values, bins);

            % Set logarithmic scale for x-axis
            set(gca, 'XScale', 'log');

            % Set axis labels and title
            switch app.spreadsheetDropDown.Value
                case 'Linearized - Resistance'
                    xlabel('Resistance (Ohms)');
                    ylabel('Frequency');
                    title('Histogram of Linearized Resistance Values');
                case 'Low Bias - Resistance'
                    xlabel('Resistance (Ohms)');
                    ylabel('Frequency');
                    title('Histogram of Low Bias Resistance Values');
                case 'Current'
                    xlabel('Current (Ampere)');
                    ylabel('Frequency');
                    title('Histogram of Current Values');
            end

            % Save the histogram as a JPEG file
            filename = sprintf('%s-%s Hysthogram.jpg', app.ADwinSettings.sample, app.spreadsheetDropDown.Value);
            filename = fullfile(app.ADwinSettings.save_dir, filename);
            % Save the histogram as a JPEG file
            saveas(gcf, filename);

            % Close the figure
            successDlg = warndlg('Hysthogram generated!', 'Success!');
            waitfor(successDlg);
            close(gcf);


        end

        % Button pushed function: newFolder
        function newFolderPushed(app, event)
            if ~exist(app.ADwinSettings.save_dir, 'dir')
                % If the directory does not exist, create it
                mkdir(app.ADwinSettings.save_dir);
            end
        end

        % Value changed function: VperVEditField_3
        function VperVEditField_3ValueChanged(app, event)
            app.makeGateSweep.Gate.V_per_V = app.VperVEditField_3.Value;

        end

        % Value changed function: StartVVEditField_2
        function StartVVEditField_2ValueChanged(app, event)
            app.makeGateSweep.Gate.startV = app.StartVVEditField_2.Value;

        end

        % Value changed function: PointsEditField_2
        function PointsEditField_2ValueChanged(app, event)
            app.makeGateSweep.Gate.points = app.PointsEditField_2.Value;

        end

        % Value changed function: DirectionEditField_2
        function DirectionEditField_2ValueChanged(app, event)
            app.makeGateSweep.Gate.sweep_dir = app.DirectionEditField_2.Value;

        end

        % Value changed function: RepeatsEditField_2
        function RepeatsEditField_2ValueChanged(app, event)
            app.makeGateSweep.Gate.repeat = app.RepeatsEditField_2.Value;

        end

        % Value changed function: MaxVVEditField_2
        function MaxVVEditField_2ValueChanged(app, event)
            app.makeGateSweep.Gate.maxV = app.MaxVVEditField_2.Value;

        end

        % Value changed function: MinVVEditField_2
        function MinVVEditField_2ValueChanged(app, event)
            app.makeGateSweep.Gate.minV = app.MinVVEditField_2.Value;

        end

        % Value changed function: RateHzEditField_2
        function RateHzEditField_2ValueChanged(app, event)
            app.makeGateSweep.Gate.scanrate = app.RateHzEditField_2.Value;

        end

        % Value changed function: BW_corrEditField_2
        function BW_corrEditField_2ValueChanged(app, event)
            app.makeGateSweep.Gate.BW_adjuster = app.BW_corrEditField_2.Value;

        end

        % Value changed function: SettlingTimemsEditField_2
        function SettlingTimemsEditField_2ValueChanged(app, event)
            app.makeGateSweep.Gate.settling_time = app.SettlingTimemsEditField_2.Value;

        end

        % Value changed function: SettlingTAutorangemsEditField_2
        function SettlingTAutorangemsEditField_2ValueChanged(app, event)
            app.makeGateSweep.Gate.settling_time_autoranging = app.SettlingTAutorangemsEditField_2.Value;

        end

        % Value changed function: InitVVEditField_2
        function InitVVEditField_2ValueChanged(app, event)
            app.makeGateSweep.Bias.initV = app.InitVVEditField_2.Value;

        end

        % Value changed function: MinVVEditField_3
        function MinVVEditField_3ValueChanged(app, event)
            app.makeGateSweep.Bias.minV = app.MinVVEditField_3.Value;

        end

        % Value changed function: MaxVVEditField_3
        function MaxVVEditField_3ValueChanged(app, event)
            app.makeGateSweep.Bias.maxV = app.MaxVVEditField_3.Value;

        end

        % Value changed function: VperVEditField_4
        function VperVEditField_4ValueChanged(app, event)
            app.makeGateSweep.Bias.V_per_V = app.VperVEditField_4.Value;

        end

        % Value changed function: dVVEditField
        function dVVEditFieldValueChanged(app, event)
            app.makeGateSweep.Bias.dV = app.dVVEditField.Value;

        end

        % Value changed function: EndVVEditField_2
        function EndVVEditField_2ValueChanged(app, event)
            app.makeGateSweep.Bias.endV = app.EndVVEditField_2.Value;

        end

        % Value changed function: RampVsEditField_3
        function RampVsEditField_3ValueChanged(app, event)
            app.makeGateSweep.Bias.ramp_rate = app.RampVsEditField_3.Value;

        end

        % Value changed function: VperVEditField_5
        function VperVEditField_5ValueChanged(app, event)
            app.makeStability.IV.V_per_V = app.VperVEditField_5.Value;

        end

        % Value changed function: StartVVEditField_3
        function StartVVEditField_3ValueChanged(app, event)
            app.makeStability.IV.startV = app.StartVVEditField_3.Value;

        end

        % Value changed function: MaxVVEditField_4
        function MaxVVEditField_4ValueChanged(app, event)
            app.makeStability.IV.maxV = app.MaxVVEditField_4.Value;

        end

        % Value changed function: MinVVEditField_4
        function MinVVEditField_4ValueChanged(app, event)
            app.makeStability.IV.minV = app.MinVVEditField_4.Value;

        end

        % Value changed function: PointsEditField_3
        function PointsEditField_3ValueChanged(app, event)
            app.makeStability.IV.points = app.PointsEditField_3.Value;

        end

        % Value changed function: DirectionEditField_3
        function DirectionEditField_3ValueChanged(app, event)
            app.makeStability.IV.sweep_dir = app.DirectionEditField_3.Value;

        end

        % Value changed function: RepeatsEditField_3
        function RepeatsEditField_3ValueChanged(app, event)
            app.makeStability.IV.repeat = app.RepeatsEditField_3.Value;

        end

        % Value changed function: RateHzEditField_3
        function RateHzEditField_3ValueChanged(app, event)
            app.makeStability.IV.scanrate = app.RateHzEditField_3.Value;

        end

        % Value changed function: BW_corrEditField_3
        function BW_corrEditField_3ValueChanged(app, event)
            app.makeStability.IV.BW_adjuster = app.BW_corrEditField_3.Value;

        end

        % Value changed function: MaxIAEditField_2
        function MaxIAEditField_2ValueChanged(app, event)
            app.makeStability.IV.maxI = app.MaxIAEditField_2.Value;

        end

        % Value changed function: SettlingTimemsEditField_3
        function SettlingTimemsEditField_3ValueChanged(app, event)
            app.makeStability.IV.settling_time = app.SettlingTimemsEditField_3.Value;

        end

        % Value changed function: TAutorangemsEditField
        function TAutorangemsEditFieldValueChanged(app, event)
            app.makeStability.IV.settling_time_autoranging = app.TAutorangemsEditField.Value;

        end

        % Value changed function: InitVVEditField_3
        function InitVVEditField_3ValueChanged(app, event)
            app.makeStability.Gate.initV = app.InitVVEditField_3.Value;

        end

        % Value changed function: MinVVEditField_5
        function MinVVEditField_5ValueChanged(app, event)
            app.makeStability.Gate.minV = app.MinVVEditField_5.Value;

        end

        % Value changed function: MaxVVEditField_5
        function MaxVVEditField_5ValueChanged(app, event)
            app.makeStability.Gate.maxV = app.MaxVVEditField_5.Value;

        end

        % Value changed function: VperVEditField_6
        function VperVEditField_6ValueChanged(app, event)
            app.makeStability.Gate.V_per_V = app.VperVEditField_6.Value;

        end

        % Value changed function: dVVEditField_2
        function dVVEditField_2ValueChanged(app, event)
            app.makeStability.Gate.dV = app.dVVEditField_2.Value;

        end

        % Value changed function: EndVVEditField_3
        function EndVVEditField_3ValueChanged(app, event)
            app.makeStability.Gate.endV = app.EndVVEditField_3.Value;

        end

        % Value changed function: RampVsEditField_4
        function RampVsEditField_4ValueChanged(app, event)
            app.makeStability.Gate.ramp_rate = app.RampVsEditField_4.Value;

        end

        % Value changed function: DirectionEditField_4
        function DirectionEditField_4ValueChanged(app, event)
            app.makeStability.Gate.sweep_dir = app.DirectionEditField_4.Value;

        end

        % Value changed function: WaitsEditField
        function WaitsEditFieldValueChanged(app, event)
            app.makeStability.Gate.waiting_time = app.WaitsEditField.Value;

        end

        % Value changed function: VperVEditField_7
        function VperVEditField_7ValueChanged(app, event)
            app.needleContact.Gate.V_per_V = app.VperVEditField_7.Value;

        end

        % Value changed function: LabelEditField
        function LabelEditFieldValueChanged(app, event)


            label = app.LabelEditField.Value;

            % Use regular expression to extract the letter and number
            tokens = regexp(label, '([A-Za-z])(\d+)', 'tokens');

            % Assign to separate variables
            letter = tokens{1}{1};           % The letter part
            letter = upper(letter); % Convert to uppercase to handle lowercase letters
            number = str2double(tokens{1}{2});  % The number part as a double

            col = double(letter) - double('A') + 1;
            row = number;

            Cellcolumns = app.CellcolumnsEditField.Value;
            Cellrows = app.CellrowsEditField.Value;
            numRows = app.Layout{app.currentLayout}.getRows();
            numCols = app.Layout{app.currentLayout}.getColumns();

            devNumbers3D = zeros(Cellrows * numRows, Cellcolumns * numCols);
            devNumbers3D = devNumbers3D';
            flag = 0;
            n = 1;

            for kY = 1:Cellcolumns
                for kX = 1:Cellrows
                    if flag
                        break;
                    end
                    for colIndex = 1:numCols
                        if flag
                            break;
                        end
                        for rowIndex = 1:numRows
                            if n <= numel(devNumbers3D)
                                devNumbers3D((kY - 1) * numCols + colIndex, (kX - 1) * numRows + rowIndex) = n;
                                n = n + 1;
                            else
                                flag = 1;
                                break;
                            end
                        end
                    end
                end
            end

            num = devNumbers3D(row, col);
            app.NrEditField.Value = num;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ESCOTestUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {545, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ESCOTestUIFigure = uifigure('Visible', 'off');
            app.ESCOTestUIFigure.Name = 'ESCO Test';
            app.ESCOTestUIFigure.CloseRequestFcn = createCallbackFcn(app, @ESCOTestUIFigureCloseRequest, true);
            app.ESCOTestUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ESCOTestUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.CameraTab = uitab(app.TabGroup);
            app.CameraTab.Title = 'Camera';
            app.CameraPanel = uipanel(app.CameraTab);
            app.CameraPanel.Title = 'Camera';
            app.SettingsPanel = uipanel(app.CameraPanel);
            app.SettingsPanel.Title = 'Settings';
            app.BirghtnessLabel = uilabel(app.SettingsPanel);
            app.BirghtnessLabel.Text = 'Brightness';
            app.SliderBrightness = uislider(app.SettingsPanel);
            app.SliderBrightness.Limits = [-64 64];
            app.SliderBrightness.ValueChangedFcn = createCallbackFcn(app, @SliderBrightnessValueChanged, true);
            app.BirghtnessLabel_2 = uilabel(app.SettingsPanel);
            app.BirghtnessLabel_2.Text = 'Exposure';
            app.SliderExposure = uislider(app.SettingsPanel);
            app.SliderExposure.Limits = [-10 0];
            app.SliderExposure.ValueChangedFcn = createCallbackFcn(app, @SliderExposureValueChanged, true);
            app.SliderExposure.Value = -4;
            app.CrosshairPanel = uipanel(app.CameraPanel);
            app.CrosshairPanel.Title = 'Crosshair';
            app.ShowCrosshairCheckBox = uicheckbox(app.CrosshairPanel);
            app.ShowCrosshairCheckBox.Text = 'Show Crosshair';
            app.ShowCircleCheckBox = uicheckbox(app.CrosshairPanel);
            app.ShowCircleCheckBox.Text = 'Show Circle';
            app.SizeSliderLabel = uilabel(app.CrosshairPanel);
            app.SizeSliderLabel.Text = 'Size';
            app.SizeSlider = uislider(app.CrosshairPanel);
            app.SizeSlider.Limits = [1 1000];
            app.SizeSlider.MinorTicks = [1 38 75 112 149 186 223 260 297 334 371 408 445 482 519 556 593 630 667 704 741 778 815 852 889 926 963 1000];
            app.SizeSlider.Value = 1;
            app.OffsetXSpinnerLabel = uilabel(app.CrosshairPanel);
            app.OffsetXSpinnerLabel.Text = 'Offset X';
            app.OffsetXSpinner = uispinner(app.CrosshairPanel);
            app.OffsetXSpinner.ValueDisplayFormat = '%.0f';
            app.Button = uibutton(app.CrosshairPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.Button.Icon = fullfile(pathToMLAPP, 'photography-icon-png-7.jpg');
            app.OffsetYSpinnerLabel = uilabel(app.CrosshairPanel);
            app.OffsetYSpinnerLabel.Text = 'Offset Y';
            app.OffsetYSpinner = uispinner(app.CrosshairPanel);
            app.OffsetYSpinner.ValueDisplayFormat = '%.0f';
            app.StopCameraButton = uibutton(app.CameraPanel, 'push');
            app.StopCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StopCamera, true);
            app.StopCameraButton.Text = 'Stop Camera';
            app.StartCameraButton = uibutton(app.CameraPanel, 'push');
            app.StartCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartCamera, true);
            app.StartCameraButton.Text = 'Start Camera';
            app.ImageCorrectionTab = uitab(app.TabGroup);
            app.ImageCorrectionTab.Title = 'Image Correction';
            app.CircledetectionPanel = uipanel(app.ImageCorrectionTab);
            app.CircledetectionPanel.Title = 'Circle detection';
            app.Button_findCircles = uibutton(app.CircledetectionPanel, 'push');
            app.Button_findCircles.ButtonPushedFcn = createCallbackFcn(app, @Button_findCirclesPushed, true);
            app.Button_findCircles.Text = '';
            app.Button_findCircles.Icon = fullfile(pathToMLAPP, 'circles_icon.jpg');
            app.MinRadiuspxSpinnerLabel = uilabel(app.CircledetectionPanel);
            app.MinRadiuspxSpinnerLabel.Text = 'Min Radius (px)';
            app.MinRadiuspxSpinner = uispinner(app.CircledetectionPanel);
            app.MinRadiuspxSpinner.ValueDisplayFormat = '%.0f';
            app.MinRadiuspxSpinner.Value = 15;
            app.MaxRadiuspxSpinnerLabel = uilabel(app.CircledetectionPanel);
            app.MaxRadiuspxSpinnerLabel.Text = 'Max Radius (px)';
            app.MaxRadiuspxSpinner = uispinner(app.CircledetectionPanel);
            app.MaxRadiuspxSpinner.ValueDisplayFormat = '%.0f';
            app.MaxRadiuspxSpinner.Value = 20;
            app.SensitivitySpinnerLabel = uilabel(app.CircledetectionPanel);
            app.SensitivitySpinnerLabel.Text = 'Sensitivity';
            app.SensitivitySpinner = uispinner(app.CircledetectionPanel);
            app.SensitivitySpinner.Step = 0.01;
            app.SensitivitySpinner.ValueDisplayFormat = '%.3f';
            app.SensitivitySpinner.Value = 0.9;
            app.CirclesSpinnerLabel = uilabel(app.CircledetectionPanel);
            app.CirclesSpinnerLabel.Text = 'Circles';
            app.CirclesSpinner = uispinner(app.CircledetectionPanel);
            app.CirclesSpinner.ValueDisplayFormat = '%.0f';
            app.CirclesSpinner.Value = 3;
            app.CirclesizeumSpinnerLabel = uilabel(app.CircledetectionPanel);
            app.CirclesizeumSpinnerLabel.Text = 'Circle size (um)';
            app.CirclesizeSpinner = uispinner(app.CircledetectionPanel);
            app.CirclesizeSpinner.ValueDisplayFormat = '%.0f';
            app.CirclesizeSpinner.Value = 50;
            app.Button_CaptureReference = uibutton(app.ImageCorrectionTab, 'push');
            app.Button_CaptureReference.ButtonPushedFcn = createCallbackFcn(app, @Button_CaptureReferencePushed, true);
            app.Button_CaptureReference.Text = 'CAPTURE REFERENCE';
            app.Button_Run_correction = uibutton(app.ImageCorrectionTab, 'push');
            app.Button_Run_correction.ButtonPushedFcn = createCallbackFcn(app, @Button_Run_correctionPushed, true);
            app.Button_Run_correction.Text = 'RUN IMAGE CORRECTION';
            app.Button_Align_rotation = uibutton(app.ImageCorrectionTab, 'push');
            app.Button_Align_rotation.ButtonPushedFcn = createCallbackFcn(app, @Button_Align_rotationPushed, true);
            app.Button_Align_rotation.Text = 'ADJUST ROTATION';
            app.RangeSpinnerLabel = uilabel(app.ImageCorrectionTab);
            app.RangeSpinnerLabel.Text = 'Range';
            app.RangeSpinner = uispinner(app.ImageCorrectionTab);
            app.RangeSpinner.Value = 30;
            app.StepLabel = uilabel(app.ImageCorrectionTab);
            app.StepLabel.Text = 'Step   ';
            app.StepSpinner = uispinner(app.ImageCorrectionTab);
            app.StepSpinner.Value = 10;
            app.MotorTab = uitab(app.TabGroup);
            app.MotorTab.Title = 'Motor';
            app.GridLayout3 = uigridlayout(app.MotorTab);
            app.ManualMotionPanel = uipanel(app.GridLayout3);
            app.ManualMotionPanel.Title = 'Manual Motion';
            app.Panel_3 = uipanel(app.ManualMotionPanel);
            app.ANGLELabel = uilabel(app.Panel_3);
            app.ANGLELabel.Text = 'ANGLE(°)';
            app.ccRotButton = uibutton(app.Panel_3, 'push');
            app.ccRotButton.ButtonPushedFcn = createCallbackFcn(app, @NegButtonPushed, true);
            app.ccRotButton.Text = '-';
            app.cRotButton = uibutton(app.Panel_3, 'push');
            app.cRotButton.ButtonPushedFcn = createCallbackFcn(app, @PosButtonPushed, true);
            app.cRotButton.Text = '+';
            app.EditStepsRot = uieditfield(app.Panel_3, 'numeric');
            app.EditStepsRot.Limits = [0 Inf];
            app.EditStepsRot.Value = 20;
            app.Panel_2 = uipanel(app.ManualMotionPanel);
            app.STAGEumLabel = uilabel(app.Panel_2);
            app.STAGEumLabel.Text = 'STAGE(um)';
            app.EditStepsMove = uieditfield(app.Panel_2, 'numeric');
            app.EditStepsMove.Limits = [0 Inf];
            app.EditStepsMove.Value = 20;
            app.upButton = uibutton(app.ManualMotionPanel, 'push');
            app.upButton.ButtonPushedFcn = createCallbackFcn(app, @MoveStageUp, true);
            app.upButton.Text = '^';
            app.leftButton = uibutton(app.ManualMotionPanel, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @MoveStageLeft, true);
            app.leftButton.Text = '<';
            app.rightButton = uibutton(app.ManualMotionPanel, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @MoveStageRight, true);
            app.rightButton.Text = '>';
            app.downButton = uibutton(app.ManualMotionPanel, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @MoveStageDown, true);
            app.downButton.Text = 'v';
            app.STAGEZmmPanel = uipanel(app.ManualMotionPanel);
            app.STAGEZmmPanel.Title = 'STAGE-Z (mm)';
            app.EditStepsNeedle = uieditfield(app.STAGEZmmPanel, 'numeric');
            app.EditStepsNeedle.Limits = [0 Inf];
            app.EditStepsNeedle.Value = 1;
            app.UpButton = uibutton(app.STAGEZmmPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.STAGEZmmPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.AbsolutePositionPanel = uipanel(app.ManualMotionPanel);
            app.AbsolutePositionPanel.Title = 'Absolute Position';
            app.DeviceMotionPanel = uipanel(app.GridLayout3);
            app.DeviceMotionPanel.Title = 'Device Motion';
            app.PadMovePanel = uipanel(app.DeviceMotionPanel);
            app.PadMovePanel.Title = 'Pad Move';
            app.padUpButton = uibutton(app.PadMovePanel, 'push');
            app.padUpButton.ButtonPushedFcn = createCallbackFcn(app, @padUpButtonPushed, true);
            app.padUpButton.Text = '^';
            app.padLeftButton = uibutton(app.PadMovePanel, 'push');
            app.padLeftButton.ButtonPushedFcn = createCallbackFcn(app, @padLeftButtonPushed, true);
            app.padLeftButton.Text = '<';
            app.padRightButton = uibutton(app.PadMovePanel, 'push');
            app.padRightButton.ButtonPushedFcn = createCallbackFcn(app, @padRightButtonPushed, true);
            app.padRightButton.Text = '>';
            app.padDownButton = uibutton(app.PadMovePanel, 'push');
            app.padDownButton.ButtonPushedFcn = createCallbackFcn(app, @padDownButtonPushed, true);
            app.padDownButton.Text = 'v';
            app.EditStepsPad = uieditfield(app.PadMovePanel, 'numeric');
            app.EditStepsPad.Limits = [0 Inf];
            app.EditStepsPad.Value = 1;
            app.GroupMovePanel = uipanel(app.DeviceMotionPanel);
            app.GroupMovePanel.Title = 'Group Move';
            app.groupUpButton = uibutton(app.GroupMovePanel, 'push');
            app.groupUpButton.ButtonPushedFcn = createCallbackFcn(app, @groupUpButtonPushed, true);
            app.groupUpButton.Text = '^';
            app.groupLeftButton = uibutton(app.GroupMovePanel, 'push');
            app.groupLeftButton.ButtonPushedFcn = createCallbackFcn(app, @groupLeftButtonPushed, true);
            app.groupLeftButton.Text = '<';
            app.groupRightButton = uibutton(app.GroupMovePanel, 'push');
            app.groupRightButton.ButtonPushedFcn = createCallbackFcn(app, @groupRightButtonPushed, true);
            app.groupRightButton.Text = '>';
            app.groupDownButton = uibutton(app.GroupMovePanel, 'push');
            app.groupDownButton.ButtonPushedFcn = createCallbackFcn(app, @groupDownButtonPushed, true);
            app.groupDownButton.Text = 'v';
            app.EditStepsGroup = uieditfield(app.GroupMovePanel, 'numeric');
            app.EditStepsGroup.Limits = [0 Inf];
            app.EditStepsGroup.Value = 1;
            app.RoutineSettingsTab = uitab(app.TabGroup);
            app.RoutineSettingsTab.Title = 'Routine Settings';
            app.TabGroup2 = uitabgroup(app.RoutineSettingsTab);
            app.MakeIVTab = uitab(app.TabGroup2);
            app.MakeIVTab.Title = 'Make IV';
            app.IVSettingsPanel = uipanel(app.MakeIVTab);
            app.IVSettingsPanel.Title = 'IV Settings';
            app.VperVEditFieldLabel = uilabel(app.IVSettingsPanel);
            app.VperVEditFieldLabel.Text = 'V per V';
            app.VperVEditField = uieditfield(app.IVSettingsPanel, 'numeric');
            app.VperVEditField.ValueChangedFcn = createCallbackFcn(app, @VperVEditFieldValueChanged, true);
            app.VperVEditField.Value = 1;
            app.MaxVVEditFieldLabel = uilabel(app.IVSettingsPanel);
            app.MaxVVEditFieldLabel.Text = 'Max V (V)';
            app.MaxVVEditField = uieditfield(app.IVSettingsPanel, 'numeric');
            app.MaxVVEditField.ValueChangedFcn = createCallbackFcn(app, @MaxVVEditFieldValueChanged, true);
            app.MaxVVEditField.Value = 0.5;
            app.StartVVEditFieldLabel = uilabel(app.IVSettingsPanel);
            app.StartVVEditFieldLabel.Text = 'Start V (V)';
            app.StartVVEditField = uieditfield(app.IVSettingsPanel, 'numeric');
            app.StartVVEditField.ValueChangedFcn = createCallbackFcn(app, @StartVVEditFieldValueChanged, true);
            app.MinVVEditFieldLabel = uilabel(app.IVSettingsPanel);
            app.MinVVEditFieldLabel.Text = 'Min V (V)';
            app.MinVVEditField = uieditfield(app.IVSettingsPanel, 'numeric');
            app.MinVVEditField.ValueChangedFcn = createCallbackFcn(app, @MinVVEditFieldValueChanged, true);
            app.MinVVEditField.Value = -0.5;
            app.PointsEditFieldLabel = uilabel(app.IVSettingsPanel);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.IVSettingsPanel, 'numeric');
            app.PointsEditField.ValueChangedFcn = createCallbackFcn(app, @PointsEditFieldValueChanged, true);
            app.PointsEditField.Value = 501;
            app.MaxIAEditFieldLabel = uilabel(app.IVSettingsPanel);
            app.MaxIAEditFieldLabel.Text = 'Max I (A)';
            app.MaxIAEditField = uieditfield(app.IVSettingsPanel, 'numeric');
            app.MaxIAEditField.ValueChangedFcn = createCallbackFcn(app, @MaxIAEditFieldValueChanged, true);
            app.MaxIAEditField.Value = 0.001;
            app.RepeatsEditFieldLabel = uilabel(app.IVSettingsPanel);
            app.RepeatsEditFieldLabel.Text = 'Repeats';
            app.RepeatsEditField = uieditfield(app.IVSettingsPanel, 'numeric');
            app.RepeatsEditField.ValueChangedFcn = createCallbackFcn(app, @RepeatsEditFieldValueChanged, true);
            app.RepeatsEditField.Value = 1;
            app.RateHzEditFieldLabel = uilabel(app.IVSettingsPanel);
            app.RateHzEditFieldLabel.Text = 'Rate (Hz)';
            app.RateHzEditField = uieditfield(app.IVSettingsPanel, 'numeric');
            app.RateHzEditField.ValueChangedFcn = createCallbackFcn(app, @RateHzEditFieldValueChanged, true);
            app.RateHzEditField.Value = 450000;
            app.SettlingTimemsEditFieldLabel = uilabel(app.IVSettingsPanel);
            app.SettlingTimemsEditFieldLabel.Text = 'Settling Time (ms)';
            app.SettlingTimemsEditField = uieditfield(app.IVSettingsPanel, 'numeric');
            app.SettlingTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @SettlingTimemsEditFieldValueChanged, true);
            app.SettlingTAutorangemsEditFieldLabel = uilabel(app.IVSettingsPanel);
            app.SettlingTAutorangemsEditFieldLabel.Text = 'Settling T Autorange (ms)';
            app.SettlingTAutorangemsEditField = uieditfield(app.IVSettingsPanel, 'numeric');
            app.SettlingTAutorangemsEditField.ValueChangedFcn = createCallbackFcn(app, @SettlingTAutorangemsEditFieldValueChanged, true);
            app.SettlingTAutorangemsEditField.Value = 200;
            app.DirectionEditFieldLabel = uilabel(app.IVSettingsPanel);
            app.DirectionEditFieldLabel.Text = 'Direction';
            app.DirectionEditField = uieditfield(app.IVSettingsPanel, 'text');
            app.DirectionEditField.ValueChangedFcn = createCallbackFcn(app, @DirectionEditFieldValueChanged, true);
            app.DirectionEditField.Value = 'up';
            app.BW_corrEditFieldLabel = uilabel(app.IVSettingsPanel);
            app.BW_corrEditFieldLabel.Text = 'BW_corr';
            app.BW_corrEditField = uieditfield(app.IVSettingsPanel, 'numeric');
            app.BW_corrEditField.ValueChangedFcn = createCallbackFcn(app, @BW_corrEditFieldValueChanged, true);
            app.BW_corrEditField.Value = 1;
            app.GateSettingsPanel = uipanel(app.MakeIVTab);
            app.GateSettingsPanel.Title = 'Gate Settings';
            app.SweepingLabel = uilabel(app.GateSettingsPanel);
            app.SweepingLabel.Text = 'Sweeping';
            app.InitVVEditFieldLabel = uilabel(app.GateSettingsPanel);
            app.InitVVEditFieldLabel.Text = 'Init V (V)';
            app.InitVVEditField = uieditfield(app.GateSettingsPanel, 'numeric');
            app.InitVVEditField.ValueChangedFcn = createCallbackFcn(app, @InitVVEditFieldValueChanged, true);
            app.TargetVVEditFieldLabel = uilabel(app.GateSettingsPanel);
            app.TargetVVEditFieldLabel.Text = 'Target V (V)';
            app.TargetVVEditField = uieditfield(app.GateSettingsPanel, 'numeric');
            app.TargetVVEditField.ValueChangedFcn = createCallbackFcn(app, @TargetVVEditFieldValueChanged, true);
            app.EndVVEditFieldLabel = uilabel(app.GateSettingsPanel);
            app.EndVVEditFieldLabel.Text = 'End V (V)';
            app.EndVVEditField = uieditfield(app.GateSettingsPanel, 'numeric');
            app.EndVVEditField.ValueChangedFcn = createCallbackFcn(app, @EndVVEditFieldValueChanged, true);
            app.WaitingsEditFieldLabel = uilabel(app.GateSettingsPanel);
            app.WaitingsEditFieldLabel.Text = 'Waiting (s)';
            app.WaitingsEditField = uieditfield(app.GateSettingsPanel, 'numeric');
            app.WaitingsEditField.ValueChangedFcn = createCallbackFcn(app, @WaitingsEditFieldValueChanged, true);
            app.VperVEditField_2Label = uilabel(app.GateSettingsPanel);
            app.VperVEditField_2Label.Text = 'V per V';
            app.VperVEditField_2 = uieditfield(app.GateSettingsPanel, 'numeric');
            app.VperVEditField_2.ValueChangedFcn = createCallbackFcn(app, @VperVEditField_2ValueChanged, true);
            app.VperVEditField_2.Value = 1;
            app.RampVsEditFieldLabel = uilabel(app.GateSettingsPanel);
            app.RampVsEditFieldLabel.Text = 'Ramp (V/s)';
            app.RampVsEditField = uieditfield(app.GateSettingsPanel, 'numeric');
            app.RampVsEditField.ValueChangedFcn = createCallbackFcn(app, @RampVsEditFieldValueChanged, true);
            app.RampVsEditField.Value = 1;
            app.GateSweepTab = uitab(app.TabGroup2);
            app.GateSweepTab.Title = 'GateSweep';
            app.GateSettingsPanel_2 = uipanel(app.GateSweepTab);
            app.GateSettingsPanel_2.Title = 'Gate Settings';
            app.GridLayout16 = uigridlayout(app.GateSettingsPanel_2);
            app.VperVEditField_3Label = uilabel(app.GridLayout16);
            app.VperVEditField_3Label.Text = 'V per V';
            app.VperVEditField_3 = uieditfield(app.GridLayout16, 'numeric');
            app.VperVEditField_3.ValueChangedFcn = createCallbackFcn(app, @VperVEditField_3ValueChanged, true);
            app.VperVEditField_3.Value = 10;
            app.MaxVVEditField_2Label = uilabel(app.GridLayout16);
            app.MaxVVEditField_2Label.Text = 'Max V (V)';
            app.MaxVVEditField_2 = uieditfield(app.GridLayout16, 'numeric');
            app.MaxVVEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxVVEditField_2ValueChanged, true);
            app.MaxVVEditField_2.Value = 50;
            app.StartVVEditField_2Label = uilabel(app.GridLayout16);
            app.StartVVEditField_2Label.Text = 'Start V (V)';
            app.StartVVEditField_2 = uieditfield(app.GridLayout16, 'numeric');
            app.StartVVEditField_2.ValueChangedFcn = createCallbackFcn(app, @StartVVEditField_2ValueChanged, true);
            app.MinVVEditField_2Label = uilabel(app.GridLayout16);
            app.MinVVEditField_2Label.Text = 'Min V (V)';
            app.MinVVEditField_2 = uieditfield(app.GridLayout16, 'numeric');
            app.MinVVEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinVVEditField_2ValueChanged, true);
            app.MinVVEditField_2.Value = -50;
            app.PointsEditField_2Label = uilabel(app.GridLayout16);
            app.PointsEditField_2Label.Text = 'Points';
            app.PointsEditField_2 = uieditfield(app.GridLayout16, 'numeric');
            app.PointsEditField_2.ValueChangedFcn = createCallbackFcn(app, @PointsEditField_2ValueChanged, true);
            app.PointsEditField_2.Value = 1001;
            app.RepeatsEditField_2Label = uilabel(app.GridLayout16);
            app.RepeatsEditField_2Label.Text = 'Repeats';
            app.RepeatsEditField_2 = uieditfield(app.GridLayout16, 'numeric');
            app.RepeatsEditField_2.ValueChangedFcn = createCallbackFcn(app, @RepeatsEditField_2ValueChanged, true);
            app.RepeatsEditField_2.Value = 1;
            app.RateHzEditField_2Label = uilabel(app.GridLayout16);
            app.RateHzEditField_2Label.Text = 'Rate (Hz)';
            app.RateHzEditField_2 = uieditfield(app.GridLayout16, 'numeric');
            app.RateHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @RateHzEditField_2ValueChanged, true);
            app.RateHzEditField_2.Value = 450000;
            app.SettlingTimemsEditField_2Label = uilabel(app.GridLayout16);
            app.SettlingTimemsEditField_2Label.Text = 'Settling Time (ms)';
            app.SettlingTimemsEditField_2 = uieditfield(app.GridLayout16, 'numeric');
            app.SettlingTimemsEditField_2.ValueChangedFcn = createCallbackFcn(app, @SettlingTimemsEditField_2ValueChanged, true);
            app.SettlingTAutorangemsEditField_2Label = uilabel(app.GridLayout16);
            app.SettlingTAutorangemsEditField_2Label.Text = 'Settling T Autorange (ms)';
            app.SettlingTAutorangemsEditField_2 = uieditfield(app.GridLayout16, 'numeric');
            app.SettlingTAutorangemsEditField_2.ValueChangedFcn = createCallbackFcn(app, @SettlingTAutorangemsEditField_2ValueChanged, true);
            app.SettlingTAutorangemsEditField_2.Value = 300;
            app.DirectionEditField_2Label = uilabel(app.GridLayout16);
            app.DirectionEditField_2Label.Text = 'Direction';
            app.DirectionEditField_2 = uieditfield(app.GridLayout16, 'text');
            app.DirectionEditField_2.ValueChangedFcn = createCallbackFcn(app, @DirectionEditField_2ValueChanged, true);
            app.DirectionEditField_2.Value = 'up';
            app.BW_corrEditField_2Label = uilabel(app.GridLayout16);
            app.BW_corrEditField_2Label.Text = 'BW_corr';
            app.BW_corrEditField_2 = uieditfield(app.GridLayout16, 'numeric');
            app.BW_corrEditField_2.ValueChangedFcn = createCallbackFcn(app, @BW_corrEditField_2ValueChanged, true);
            app.BW_corrEditField_2.Value = 1;
            app.BiasSettingsPanel = uipanel(app.GateSweepTab);
            app.BiasSettingsPanel.Title = 'Bias Settings';
            app.InitVVEditField_2Label = uilabel(app.BiasSettingsPanel);
            app.InitVVEditField_2Label.Text = 'Init V (V)';
            app.InitVVEditField_2 = uieditfield(app.BiasSettingsPanel, 'numeric');
            app.InitVVEditField_2.ValueChangedFcn = createCallbackFcn(app, @InitVVEditField_2ValueChanged, true);
            app.MinVVEditField_3Label = uilabel(app.BiasSettingsPanel);
            app.MinVVEditField_3Label.Text = 'Min  V (V)';
            app.MinVVEditField_3 = uieditfield(app.BiasSettingsPanel, 'numeric');
            app.MinVVEditField_3.ValueChangedFcn = createCallbackFcn(app, @MinVVEditField_3ValueChanged, true);
            app.MinVVEditField_3.Value = 0.1;
            app.MaxVVEditField_3Label = uilabel(app.BiasSettingsPanel);
            app.MaxVVEditField_3Label.Text = 'Max  V (V)';
            app.MaxVVEditField_3 = uieditfield(app.BiasSettingsPanel, 'numeric');
            app.MaxVVEditField_3.ValueChangedFcn = createCallbackFcn(app, @MaxVVEditField_3ValueChanged, true);
            app.MaxVVEditField_3.Value = 0.1;
            app.dVVEditFieldLabel = uilabel(app.BiasSettingsPanel);
            app.dVVEditFieldLabel.Text = 'dV (V)';
            app.dVVEditField = uieditfield(app.BiasSettingsPanel, 'numeric');
            app.dVVEditField.ValueChangedFcn = createCallbackFcn(app, @dVVEditFieldValueChanged, true);
            app.dVVEditField.Value = 0.1;
            app.VperVEditField_4Label = uilabel(app.BiasSettingsPanel);
            app.VperVEditField_4Label.Text = 'V per V';
            app.VperVEditField_4 = uieditfield(app.BiasSettingsPanel, 'numeric');
            app.VperVEditField_4.ValueChangedFcn = createCallbackFcn(app, @VperVEditField_4ValueChanged, true);
            app.VperVEditField_4.Value = 1;
            app.EndVVEditField_2Label = uilabel(app.BiasSettingsPanel);
            app.EndVVEditField_2Label.Text = 'End V (V)';
            app.EndVVEditField_2 = uieditfield(app.BiasSettingsPanel, 'numeric');
            app.EndVVEditField_2.ValueChangedFcn = createCallbackFcn(app, @EndVVEditField_2ValueChanged, true);
            app.RampVsEditField_3Label = uilabel(app.BiasSettingsPanel);
            app.RampVsEditField_3Label.Text = 'Ramp (V/s)';
            app.RampVsEditField_3 = uieditfield(app.BiasSettingsPanel, 'numeric');
            app.RampVsEditField_3.ValueChangedFcn = createCallbackFcn(app, @RampVsEditField_3ValueChanged, true);
            app.RampVsEditField_3.Value = 0.1;
            app.StabilityTab = uitab(app.TabGroup2);
            app.StabilityTab.Title = 'Stability';
            app.IVSettingsPanel_2 = uipanel(app.StabilityTab);
            app.IVSettingsPanel_2.Title = 'IV Settings';
            app.VperVEditField_5Label = uilabel(app.IVSettingsPanel_2);
            app.VperVEditField_5Label.Text = 'V per V';
            app.VperVEditField_5 = uieditfield(app.IVSettingsPanel_2, 'numeric');
            app.VperVEditField_5.ValueChangedFcn = createCallbackFcn(app, @VperVEditField_5ValueChanged, true);
            app.VperVEditField_5.Value = 1;
            app.MaxVVEditField_4Label = uilabel(app.IVSettingsPanel_2);
            app.MaxVVEditField_4Label.Text = 'Max V (V)';
            app.MaxVVEditField_4 = uieditfield(app.IVSettingsPanel_2, 'numeric');
            app.MaxVVEditField_4.ValueChangedFcn = createCallbackFcn(app, @MaxVVEditField_4ValueChanged, true);
            app.MaxVVEditField_4.Value = 0.2;
            app.StartVVEditField_3Label = uilabel(app.IVSettingsPanel_2);
            app.StartVVEditField_3Label.Text = 'Start V (V)';
            app.StartVVEditField_3 = uieditfield(app.IVSettingsPanel_2, 'numeric');
            app.StartVVEditField_3.ValueChangedFcn = createCallbackFcn(app, @StartVVEditField_3ValueChanged, true);
            app.MinVVEditField_4Label = uilabel(app.IVSettingsPanel_2);
            app.MinVVEditField_4Label.Text = 'Min V (V)';
            app.MinVVEditField_4 = uieditfield(app.IVSettingsPanel_2, 'numeric');
            app.MinVVEditField_4.ValueChangedFcn = createCallbackFcn(app, @MinVVEditField_4ValueChanged, true);
            app.MinVVEditField_4.Value = -0.2;
            app.PointsEditField_3Label = uilabel(app.IVSettingsPanel_2);
            app.PointsEditField_3Label.Text = 'Points';
            app.PointsEditField_3 = uieditfield(app.IVSettingsPanel_2, 'numeric');
            app.PointsEditField_3.ValueChangedFcn = createCallbackFcn(app, @PointsEditField_3ValueChanged, true);
            app.PointsEditField_3.Value = 501;
            app.RepeatsEditField_3Label = uilabel(app.IVSettingsPanel_2);
            app.RepeatsEditField_3Label.Text = 'Repeats';
            app.RepeatsEditField_3 = uieditfield(app.IVSettingsPanel_2, 'numeric');
            app.RepeatsEditField_3.ValueChangedFcn = createCallbackFcn(app, @RepeatsEditField_3ValueChanged, true);
            app.RepeatsEditField_3.Value = 1;
            app.RateHzEditField_3Label = uilabel(app.IVSettingsPanel_2);
            app.RateHzEditField_3Label.Text = 'Rate (Hz)';
            app.RateHzEditField_3 = uieditfield(app.IVSettingsPanel_2, 'numeric');
            app.RateHzEditField_3.ValueChangedFcn = createCallbackFcn(app, @RateHzEditField_3ValueChanged, true);
            app.RateHzEditField_3.Value = 450000;
            app.SettlingTimemsEditField_3Label = uilabel(app.IVSettingsPanel_2);
            app.SettlingTimemsEditField_3Label.Text = 'Settling Time (ms)';
            app.SettlingTimemsEditField_3 = uieditfield(app.IVSettingsPanel_2, 'numeric');
            app.SettlingTimemsEditField_3.ValueChangedFcn = createCallbackFcn(app, @SettlingTimemsEditField_3ValueChanged, true);
            app.TAutorangemsEditFieldLabel = uilabel(app.IVSettingsPanel_2);
            app.TAutorangemsEditFieldLabel.Text = 'T Autorange (ms)';
            app.TAutorangemsEditField = uieditfield(app.IVSettingsPanel_2, 'numeric');
            app.TAutorangemsEditField.ValueChangedFcn = createCallbackFcn(app, @TAutorangemsEditFieldValueChanged, true);
            app.TAutorangemsEditField.Value = 200;
            app.DirectionEditField_3Label = uilabel(app.IVSettingsPanel_2);
            app.DirectionEditField_3Label.Text = 'Direction';
            app.DirectionEditField_3 = uieditfield(app.IVSettingsPanel_2, 'text');
            app.DirectionEditField_3.ValueChangedFcn = createCallbackFcn(app, @DirectionEditField_3ValueChanged, true);
            app.DirectionEditField_3.Value = 'up';
            app.BW_corrEditField_3Label = uilabel(app.IVSettingsPanel_2);
            app.BW_corrEditField_3Label.Text = 'BW_corr';
            app.BW_corrEditField_3 = uieditfield(app.IVSettingsPanel_2, 'numeric');
            app.BW_corrEditField_3.ValueChangedFcn = createCallbackFcn(app, @BW_corrEditField_3ValueChanged, true);
            app.BW_corrEditField_3.Value = 1;
            app.MaxIAEditField_2Label = uilabel(app.IVSettingsPanel_2);
            app.MaxIAEditField_2Label.Text = 'Max I (A)';
            app.MaxIAEditField_2 = uieditfield(app.IVSettingsPanel_2, 'numeric');
            app.MaxIAEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxIAEditField_2ValueChanged, true);
            app.GateSettingsPanel_3 = uipanel(app.StabilityTab);
            app.GateSettingsPanel_3.Title = 'Gate Settings';
            app.InitVVEditField_3Label = uilabel(app.GateSettingsPanel_3);
            app.InitVVEditField_3Label.Text = 'Init V (V)';
            app.InitVVEditField_3 = uieditfield(app.GateSettingsPanel_3, 'numeric');
            app.InitVVEditField_3.ValueChangedFcn = createCallbackFcn(app, @InitVVEditField_3ValueChanged, true);
            app.MinVVEditField_5Label = uilabel(app.GateSettingsPanel_3);
            app.MinVVEditField_5Label.Text = 'Min  V (V)';
            app.MinVVEditField_5 = uieditfield(app.GateSettingsPanel_3, 'numeric');
            app.MinVVEditField_5.ValueChangedFcn = createCallbackFcn(app, @MinVVEditField_5ValueChanged, true);
            app.MinVVEditField_5.Value = -0.5;
            app.MaxVVEditField_5Label = uilabel(app.GateSettingsPanel_3);
            app.MaxVVEditField_5Label.Text = 'Max  V (V)';
            app.MaxVVEditField_5 = uieditfield(app.GateSettingsPanel_3, 'numeric');
            app.MaxVVEditField_5.ValueChangedFcn = createCallbackFcn(app, @MaxVVEditField_5ValueChanged, true);
            app.MaxVVEditField_5.Value = 0.5;
            app.dVVEditField_2Label = uilabel(app.GateSettingsPanel_3);
            app.dVVEditField_2Label.Text = 'dV (V)';
            app.dVVEditField_2 = uieditfield(app.GateSettingsPanel_3, 'numeric');
            app.dVVEditField_2.ValueChangedFcn = createCallbackFcn(app, @dVVEditField_2ValueChanged, true);
            app.dVVEditField_2.Value = 0.001;
            app.VperVEditField_6Label = uilabel(app.GateSettingsPanel_3);
            app.VperVEditField_6Label.Text = 'V per V';
            app.VperVEditField_6 = uieditfield(app.GateSettingsPanel_3, 'numeric');
            app.VperVEditField_6.ValueChangedFcn = createCallbackFcn(app, @VperVEditField_6ValueChanged, true);
            app.VperVEditField_6.Value = 10;
            app.EndVVEditField_3Label = uilabel(app.GateSettingsPanel_3);
            app.EndVVEditField_3Label.Text = 'End V (V)';
            app.EndVVEditField_3 = uieditfield(app.GateSettingsPanel_3, 'numeric');
            app.EndVVEditField_3.ValueChangedFcn = createCallbackFcn(app, @EndVVEditField_3ValueChanged, true);
            app.RampVsEditField_4Label = uilabel(app.GateSettingsPanel_3);
            app.RampVsEditField_4Label.Text = 'Ramp (V/s)';
            app.RampVsEditField_4 = uieditfield(app.GateSettingsPanel_3, 'numeric');
            app.RampVsEditField_4.ValueChangedFcn = createCallbackFcn(app, @RampVsEditField_4ValueChanged, true);
            app.RampVsEditField_4.Value = 0.5;
            app.DirectionEditField_4Label = uilabel(app.GateSettingsPanel_3);
            app.DirectionEditField_4Label.Text = 'Direction';
            app.DirectionEditField_4 = uieditfield(app.GateSettingsPanel_3, 'text');
            app.DirectionEditField_4.ValueChangedFcn = createCallbackFcn(app, @DirectionEditField_4ValueChanged, true);
            app.DirectionEditField_4.Value = 'up';
            app.WaitsEditFieldLabel = uilabel(app.GateSettingsPanel_3);
            app.WaitsEditFieldLabel.Text = 'Wait (s)';
            app.WaitsEditField = uieditfield(app.GateSettingsPanel_3, 'numeric');
            app.WaitsEditField.ValueChangedFcn = createCallbackFcn(app, @WaitsEditFieldValueChanged, true);
            app.WaitsEditField.Value = 0.1;
            app.GeneralSettingsTab = uitab(app.TabGroup);
            app.GeneralSettingsTab.Title = 'General Settings';
            app.StagePanel = uipanel(app.GeneralSettingsTab);
            app.StagePanel.Title = 'Stage';
            app.VelocityXYEditFieldLabel = uilabel(app.StagePanel);
            app.VelocityXYEditFieldLabel.Text = 'Velocity(XY)';
            app.VelocityXYEditField = uieditfield(app.StagePanel, 'numeric');
            app.VelocityXYEditField.ValueChangedFcn = createCallbackFcn(app, @VelocityXYEditFieldValueChanged, true);
            app.VelocityXYEditField.Value = 100;
            app.TemperatureEditFieldLabel = uilabel(app.StagePanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.StagePanel, 'numeric');
            app.TemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureEditFieldValueChanged, true);
            app.TemperatureEditField.Value = 300;
            app.VelocityXYEditFieldLabel_2 = uilabel(app.StagePanel);
            app.VelocityXYEditFieldLabel_2.Text = 'Velocity(Z)';
            app.VelocityZEditField = uieditfield(app.StagePanel, 'numeric');
            app.VelocityZEditField.ValueChangedFcn = createCallbackFcn(app, @VelocityZEditFieldValueChanged, true);
            app.VelocityZEditField.Value = 100;
            app.SamplePanel = uipanel(app.GeneralSettingsTab);
            app.SamplePanel.Title = 'Sample';
            app.SaveDirectoryEditFieldLabel = uilabel(app.SamplePanel);
            app.SaveDirectoryEditFieldLabel.Text = 'Save Directory';
            app.SaveDirectoryEditField = uieditfield(app.SamplePanel, 'text');
            app.SaveDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @SaveDirectoryEditFieldValueChanged, true);
            app.SaveDirectoryEditField.Value = 'C:\Users\lab405\Documents\Samples\moan\SQD3T\GaTran1';
            app.SampleNameEditFieldLabel = uilabel(app.SamplePanel);
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.SampleNameEditField = uieditfield(app.SamplePanel, 'text');
            app.SampleNameEditField.ValueChangedFcn = createCallbackFcn(app, @SampleNameEditFieldValueChanged, true);
            app.SampleNameEditField.Value = 'graphStripes';
            app.newFolder = uibutton(app.SamplePanel, 'push');
            app.newFolder.ButtonPushedFcn = createCallbackFcn(app, @newFolderPushed, true);
            app.newFolder.Text = '';
            app.newFolder.Icon = fullfile(pathToMLAPP, 'newFolder_logo.png');
            app.GeometryPanel = uipanel(app.GeneralSettingsTab);
            app.GeometryPanel.Title = 'Geometry';
            app.DropDown = uidropdown(app.GeometryPanel);
            app.DropDown.Items = {'Custom', '2TGNR', 'DQD'};
            app.DropDown.ItemsData = {'0', '1', '2'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Placeholder = '%d';
            app.DropDown.Value = '1';
            app.SelectDeviceLabel = uilabel(app.GeometryPanel);
            app.SelectDeviceLabel.Text = 'Select Device:';
            app.SelectNrofCellsLabel = uilabel(app.GeometryPanel);
            app.SelectNrofCellsLabel.Text = 'Select Nr. of Cells:';
            app.CellcolumnsEditField_2Label = uilabel(app.GeometryPanel);
            app.CellcolumnsEditField_2Label.Text = 'Cell columns';
            app.CellcolumnsEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.CellcolumnsEditField.ValueChangedFcn = createCallbackFcn(app, @CellcolumnsEditFieldValueChanged, true);
            app.CellcolumnsEditField.Value = 4;
            app.CellcolumnsEditField_2Label_2 = uilabel(app.GeometryPanel);
            app.CellcolumnsEditField_2Label_2.Text = 'Cell rows';
            app.CellrowsEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.CellrowsEditField.ValueChangedFcn = createCallbackFcn(app, @CellrowsEditFieldValueChanged, true);
            app.CellrowsEditField.Value = 5;
            app.StageCorrectionFactorsPanel = uipanel(app.GeneralSettingsTab);
            app.StageCorrectionFactorsPanel.Title = 'Stage Correction Factors';
            app.GridLayout15 = uigridlayout(app.StageCorrectionFactorsPanel);
            app.StepfactorXdirectionEditFieldLabel = uilabel(app.GridLayout15);
            app.StepfactorXdirectionEditFieldLabel.Text = 'Step factor X-direction';
            app.StepfactorXdirectionEditField = uieditfield(app.GridLayout15, 'numeric');
            app.StepfactorXdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @StepfactorXdirectionEditFieldValueChanged, true);
            app.StepfactorXdirectionEditField.Value = 0.5;
            app.StepfactorYdirectionEditFieldLabel = uilabel(app.GridLayout15);
            app.StepfactorYdirectionEditFieldLabel.Text = 'Step factor Y-direction';
            app.StepfactorYdirectionEditField = uieditfield(app.GridLayout15, 'numeric');
            app.StepfactorYdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @StepfactorYdirectionEditFieldValueChanged, true);
            app.StepfactorYdirectionEditField.Value = 0.5;
            app.HysthfactorXdirectionEditFieldLabel = uilabel(app.GridLayout15);
            app.HysthfactorXdirectionEditFieldLabel.Text = 'Hysth factor X-direction';
            app.HysthfactorXdirectionEditField = uieditfield(app.GridLayout15, 'numeric');
            app.HysthfactorXdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @HysthfactorXdirectionEditFieldValueChanged, true);
            app.HysthfactorXdirectionEditField.Value = 1;
            app.HysthfactorYdirectionEditFieldLabel = uilabel(app.GridLayout15);
            app.HysthfactorYdirectionEditFieldLabel.Text = 'Hysth factor Y-direction';
            app.HysthfactorYdirectionEditField = uieditfield(app.GridLayout15, 'numeric');
            app.HysthfactorYdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @HysthfactorYdirectionEditFieldValueChanged, true);
            app.HysthfactorYdirectionEditField.Value = 1;
            app.INITIALIZESTAGEButton = uibutton(app.GeneralSettingsTab, 'push');
            app.INITIALIZESTAGEButton.ButtonPushedFcn = createCallbackFcn(app, @INITIALIZESTAGEButtonPushed, true);
            app.INITIALIZESTAGEButton.Text = 'INITIALIZE STAGE';
            app.ABORTINITIALIZATIONButton = uibutton(app.GeneralSettingsTab, 'push');
            app.ABORTINITIALIZATIONButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTINITIALIZATIONButtonPushed, true);
            app.ABORTINITIALIZATIONButton.Text = 'ABORT INITIALIZATION';
            app.DeviceNrEditFieldLabel = uilabel(app.GeneralSettingsTab);
            app.DeviceNrEditFieldLabel.Text = 'Device Nr.';
            app.DeviceNrEditField = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.DeviceNrEditField.ValueDisplayFormat = '%0d';
            app.DeviceNrEditField.Value = 1;
            app.RotationLabel = uilabel(app.GeneralSettingsTab);
            app.RotationLabel.Text = 'Rotation?';
            app.RotationSwitch = uiswitch(app.GeneralSettingsTab, 'toggle');
            app.RotationSwitch.Items = {'No', 'Yes'};
            app.RotationSwitch.Value = 'No';
            app.DeviceLabelEditFieldLabel = uilabel(app.GeneralSettingsTab);
            app.LabelEditField = uieditfield(app.GeneralSettingsTab, 'text');
            app.LabelEditField.ValueChangedFcn = createCallbackFcn(app, @LabelEditFieldValueChanged, true);
            app.LabelEditField.Value = 'A1';
            app.LabelEditField_2Label = uilabel(app.GeneralSettingsTab);
            app.LabelEditField_2Label.Text = '=Nr. ';
            app.NrEditField = uieditfield(app.GeneralSettingsTab, 'text');
            app.NrEditField.Value = '1';
            app.MeasureTab = uitab(app.TabGroup);
            app.MeasureTab.Title = 'Measure';
            app.NeedlePlot = uiaxes(app.MeasureTab);
            title(app.NeedlePlot, 'Probe Contact')
            xlabel(app.NeedlePlot, 'time')
            ylabel(app.NeedlePlot, 'Current')
            app.ContactButton = uibutton(app.MeasureTab, 'push');
            app.ContactButton.ButtonPushedFcn = createCallbackFcn(app, @ContactButtonPushed, true);
            app.ContactButton.Text = 'Contact';
            app.ProcessesPanel = uipanel(app.MeasureTab);
            app.ProcessesPanel.Title = 'Processes';
            app.STARTCHIPButton = uibutton(app.ProcessesPanel, 'push');
            app.STARTCHIPButton.ButtonPushedFcn = createCallbackFcn(app, @STARTCHIPButtonPushed, true);
            app.STARTCHIPButton.Text = 'START CHIP';
            app.ListBox = uilistbox(app.ProcessesPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.ClearListButton = uibutton(app.ProcessesPanel, 'push');
            app.ClearListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearListButtonPushed, true);
            app.ClearListButton.Text = 'Clear List';
            app.OperationsSelection = uidropdown(app.ProcessesPanel);
            app.OperationsSelection.Items = {'<Select Operation>', 'Capture Image', 'Make IV', 'Make Gate Sweep', 'Make Stability Diagram'};
            app.OperationsSelection.Value = '<Select Operation>';
            app.AddButton = uibutton(app.ProcessesPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.STARTDEVICEButton = uibutton(app.ProcessesPanel, 'push');
            app.STARTDEVICEButton.ButtonPushedFcn = createCallbackFcn(app, @STARTDEVICEButtonPushed, true);
            app.STARTDEVICEButton.Text = 'START DEVICE';
            app.PAUSEButton = uibutton(app.MeasureTab, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Text = 'PAUSE';
            app.AbortButton = uibutton(app.MeasureTab, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.TrigSwitchBoxButton = uibutton(app.MeasureTab, 'push');
            app.TrigSwitchBoxButton.ButtonPushedFcn = createCallbackFcn(app, @TrigSwitchBoxButtonPushed, true);
            app.TrigSwitchBoxButton.Text = 'Trig. SwitchBox';
            app.STOPButton = uibutton(app.MeasureTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.DropDown_2Label = uilabel(app.MeasureTab);
            app.DropDown_2Label.Text = '';
            app.DropDown_needleSide = uidropdown(app.MeasureTab);
            app.DropDown_needleSide.Items = {'Left Needle', 'Right Needle'};
            app.DropDown_needleSide.ValueChangedFcn = createCallbackFcn(app, @DropDown_needleSideValueChanged, true);
            app.DropDown_needleSide.Value = 'Left Needle';
            app.LLampLabel = uilabel(app.MeasureTab);
            app.LLampLabel.Text = 'L';
            app.LLamp = uilamp(app.MeasureTab);
            app.RLampLabel = uilabel(app.MeasureTab);
            app.RLampLabel.Text = 'R';
            app.RLamp = uilamp(app.MeasureTab);
            app.ThresholdEditFieldLabel = uilabel(app.MeasureTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.MeasureTab, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 2e-09;
            app.RuntimesEditFieldLabel = uilabel(app.MeasureTab);
            app.RuntimesEditFieldLabel.Text = 'Runtime(s)';
            app.RuntimesEditField = uieditfield(app.MeasureTab, 'numeric');
            app.RuntimesEditField.ValueChangedFcn = createCallbackFcn(app, @RuntimesEditFieldValueChanged, true);
            app.RuntimesEditField.Value = 1.2;
            app.MotionSwitchLabel = uilabel(app.MeasureTab);
            app.MotionSwitchLabel.Text = 'Motion';
            app.MotionSwitch = uiswitch(app.MeasureTab, 'toggle');
            app.MotionSwitch.ValueChangedFcn = createCallbackFcn(app, @TestMotiononlySwitchValueChanged, true);
            app.CaptureSwitchLabel = uilabel(app.MeasureTab);
            app.CaptureSwitchLabel.Text = 'Capture';
            app.CaptureSwitch = uiswitch(app.MeasureTab, 'toggle');
            app.CaptureSwitch.ValueChangedFcn = createCallbackFcn(app, @CaptureSwitchValueChanged, true);
            app.CaptureSwitch.Value = 'On';
            app.ReferenceLabel = uilabel(app.MeasureTab);
            app.ReferenceLabel.Text = 'Reference';
            app.TestLabel = uilabel(app.MeasureTab);
            app.TestLabel.Text = 'Test';
            app.VperVEditField_7Label = uilabel(app.MeasureTab);
            app.VperVEditField_7Label.Text = 'V per V';
            app.VperVEditField_7 = uieditfield(app.MeasureTab, 'numeric');
            app.VperVEditField_7.ValueChangedFcn = createCallbackFcn(app, @VperVEditField_7ValueChanged, true);
            app.VperVEditField_7.Value = 10;
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.GenerateSpreadsheetButton = uibutton(app.DataTab, 'push');
            app.GenerateSpreadsheetButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSpreadsheetButtonPushed, true);
            app.GenerateSpreadsheetButton.Text = 'Generate Spreadsheet';
            app.spreadsheetDropDown = uidropdown(app.DataTab);
            app.spreadsheetDropDown.Items = {'Gap Opening', 'Linearized - Resistance', 'Low Bias - Resistance', 'Resistance - 04V', 'Linearity', 'Current'};
            app.spreadsheetDropDown.Value = 'Gap Opening';
            app.GenerateHysthogramButton = uibutton(app.DataTab, 'push');
            app.GenerateHysthogramButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateHysthogramButtonPushed, true);
            app.GenerateHysthogramButton.Text = 'Generate Hysthogram';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.STOPMOVEButton = uibutton(app.LeftPanel, 'push');
            app.STOPMOVEButton.ButtonPushedFcn = createCallbackFcn(app, @STOPMOVEButtonPushed, true);
            app.STOPMOVEButton.Text = 'STOP MOVE';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIvideo = uiaxes(app.RightPanel);
            app.UIvideo.XLimitMethod = 'tight';
            app.UIvideo.YLimitMethod = 'tight';
            app.UIvideo.XTick = [];
            app.UIvideo.YTick = [];
            app.UIvideo.ZTick = [];
            % Show the figure after all components are created
            app.ESCOTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STG2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ESCOTestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ESCOTestUIFigure)
        end
    end
end