classdef ESCO_Test_GUI_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ESCOTestUIFigure        matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        MotorTab                matlab.ui.container.Tab
        MotionPanel             matlab.ui.container.Panel
        STOPMOVEButton          matlab.ui.control.Button
        ZeroButton              matlab.ui.control.Button
        PosButton               matlab.ui.control.Button
        AmountEditField         matlab.ui.control.NumericEditField
        AmountEditFieldLabel    matlab.ui.control.Label
        NegButton               matlab.ui.control.Button
        PositionEditField       matlab.ui.control.NumericEditField
        PositionEditFieldLabel  matlab.ui.control.Label
        ConnectionPanel         matlab.ui.container.Panel
        InfoEditField           matlab.ui.control.EditField
        InfoEditFieldLabel      matlab.ui.control.Label
        DisconnectButton        matlab.ui.control.Button
        ConnectedLamp           matlab.ui.control.Lamp
        ConnectedLampLabel      matlab.ui.control.Label
        ConnectButton           matlab.ui.control.Button
        PortDropDown            matlab.ui.control.DropDown
        PortDropDownLabel       matlab.ui.control.Label
        CameraTab               matlab.ui.container.Tab
        CameraPanel             matlab.ui.container.Panel
        CrosshairPanel          matlab.ui.container.Panel
        ShowCircleCheckBox      matlab.ui.control.CheckBox
        OffsetYSpinner          matlab.ui.control.Spinner
        OffsetYSpinnerLabel     matlab.ui.control.Label
        OffsetXSpinner          matlab.ui.control.Spinner
        OffsetXSpinnerLabel     matlab.ui.control.Label
        SizeSlider              matlab.ui.control.Slider
        SizeSliderLabel         matlab.ui.control.Label
        ShowCrosshairCheckBox   matlab.ui.control.CheckBox
        StopCameraButton        matlab.ui.control.Button
        StartCameraButton       matlab.ui.control.Button
        QUITButton              matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        UIvideo                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Settings            % application Settings from Class ETG1
        Motor               % ESCO Motor Object
        MotorPollTimer      % Timer for polling Status & Position
        CameraDAQTimer      % Timer for Camera DAQ
        Vid                 % Video Object
        Frame               % Image Data Frame
    end
    
    methods (Access = public)
        
        function timerMotorPollCallback(app, ~, ~)
            if ~app.Settings.Motor.SerialLock
                app.Motor = Get_Position(app.Motor);
                app.Motor = Get_Status(app.Motor);
                app.PositionEditField.Value = double(app.Motor.Position);
                % Display Status somewhere ;)
            end
        end
        function timerCameraDAQ(app, ~, ~)
            app.Frame = peekdata(app.Vid,1);                % Get latest Frame
            flushdata(app.Vid);                             % Flush everything else to not fill up memory
            image(0,0,app.Frame,'Parent',app.UIvideo);      % Put Frame into display
            app.CalcCC();                                   % Calc Crosshair Positions
            if app.ShowCrosshairCheckBox.Value              % Draw Crosshair if needed
                hold(app.UIvideo,"on");
                line(app.UIvideo,[app.Settings.Video.CHC.Xc app.Settings.Video.CHC.Xc],[app.Settings.Video.CHC.Yt app.Settings.Video.CHC.Yb],'Color','red');    % Draw Vertical Line
                line(app.UIvideo,[app.Settings.Video.CHC.Xl app.Settings.Video.CHC.Xr],[app.Settings.Video.CHC.Yc app.Settings.Video.CHC.Yc],'Color','red');    % Draw Horizontal Line
            end
            if app.ShowCircleCheckBox.Value                 % Draw Circle if needed
                rectangle('Parent',app.UIvideo, ...
                            'Position',[app.Settings.Video.CHC.Xc-20 app.Settings.Video.CHC.Yc-20 40 40], ...
                            'EdgeColor','red', 'Curvature',[1 1]);    % Draw bounding Rectangle
                hold(app.UIvideo,"off");
            end
        end
        function CalcCC(app)    % Calculate Crosshair Coordinates
            app.Settings.Video.CHC.Xc = app.Settings.Video.VidResX/2 + app.OffsetXSpinner.Value;                       % X-Center
            app.Settings.Video.CHC.Yc = app.Settings.Video.VidResY/2 + app.OffsetYSpinner.Value;                       % Y-Center
            Xleft = app.Settings.Video.CHC.Xc - app.SizeSlider.Value;
            if Xleft < 0
                app.Settings.Video.CHC.Xl = 0;
            else
                app.Settings.Video.CHC.Xl = Xleft;
            end
            Xright = app.Settings.Video.CHC.Xc + app.SizeSlider.Value;
            if Xright > app.Settings.Video.VidResX
                app.Settings.Video.CHC.Xr = app.Settings.Video.VidResX;
            else
                app.Settings.Video.CHC.Xr = Xright;
            end
            Ytop = app.Settings.Video.CHC.Yc - app.SizeSlider.Value;
            if Ytop < 0
                app.Settings.Video.CHC.Yt = 0;
            else
                app.Settings.Video.CHC.Yt = Ytop;
            end
            Ybottom = app.Settings.Video.CHC.Yc + app.SizeSlider.Value;
            if Ybottom > app.Settings.Video.VidResY
                app.Settings.Video.CHC.Yb = app.Settings.Video.VidResY;
            else
                app.Settings.Video.CHC.Yb = Ybottom;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Settings = ETG1;
            app.MotionPanel.Enable = "off";
            app.PortDropDown.Enable = "on";
            app.ConnectButton.Enable = "on";
            app.DisconnectButton.Enable = "off";
            app.StartCameraButton.Enable = "on";
            app.StopCameraButton.Enable = "off";
            app.CrosshairPanel.Enable = "off";
            app.PortDropDown.Items = flip(serialportlist);
            app.ConnectedLamp.Color = "red";
            app.AmountEditField.Value = 10;
            app.SizeSlider.Value = 20;
            app.MotorPollTimer = timer('StartDelay', 1.0, 'Period', 1.0, 'ExecutionMode', 'fixedRate', 'TasksToExecute', Inf, 'TimerFcn', @app.timerMotorPollCallback);
            app.CameraDAQTimer = timer('StartDelay', 0.1, 'Period', 0.1, 'ExecutionMode', 'fixedRate', 'TasksToExecute', Inf, 'TimerFcn', @app.timerCameraDAQ);

        end

        % Button pushed function: ConnectButton
        function ConnectMotor(app, event)
            app.Motor = ESCO(app.PortDropDown.Value, app.Settings.Motor.Simulate);
            app.Motor = Connect(app.Motor);
            app.Motor = Set_Acceleration(app.Motor, app.Settings.Motor.Acceleration);
            app.Motor = Set_Velocity (app.Motor, app.Settings.Motor.Velocity);
            app.ConnectedLamp.Color = "green";
            app.InfoEditField.Value = app.Motor.Identity + " ||" + app.Motor.Version;
            start(app.MotorPollTimer);
            app.MotionPanel.Enable = "on";
            app.PortDropDown.Enable = "off";
            app.ConnectButton.Enable = "off";
            app.DisconnectButton.Enable = "on";
            app.Settings.Motor.Connected = true;
        end

        % Button pushed function: DisconnectButton
        function DisconnectMotor(app, event)
            stop(app.MotorPollTimer);
            app.Motor = Disconnect(app.Motor);
            app.MotionPanel.Enable = "off";
            app.PortDropDown.Enable = "on";
            app.ConnectButton.Enable = "on";
            app.DisconnectButton.Enable = "off";
            app.ConnectedLamp.Color = "red";
            app.InfoEditField.Value = "";
            app.Settings.Motor.Connected = false;
        end

        % Button pushed function: PosButton
        function PosButtonPushed(app, event)
            app.Settings.Motor.SerialLock=true;
            app.Motor = Move_REL(app.Motor, app.AmountEditField.Value);
            app.Motor = WaitMoveDone(app.Motor);
            app.Settings.Motor.SerialLock=false;
        end

        % Button pushed function: NegButton
        function NegButtonPushed(app, event)
            app.Settings.Motor.SerialLock=true;       
            app.Motor = Move_REL(app.Motor, (-1)*app.AmountEditField.Value);
            app.Motor = WaitMoveDone(app.Motor); 
            app.Settings.Motor.SerialLock=false;
        end

        % Value changed function: PositionEditField
        function PositionEditFieldValueChanged(app, event)
            app.Settings.Motor.SerialLock=true;
            value = app.PositionEditField.Value;
            app.Motor = Move_ABS(app.Motor, value);
            app.Settings.Motor.SerialLock=false;
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
             app.Settings.Motor.SerialLock=true;
             app.Motor = Zero_Position(app.Motor);
             app.Settings.Motor.SerialLock=false;
        end

        % Button pushed function: STOPMOVEButton
        function STOPMOVEButtonPushed(app, event)
              app.Settings.Motor.SerialLock=true;
              app.Motor = Stop(app.Motor);
              app.Settings.Motor.SerialLock=false;
        end

        % Close request function: ESCOTestUIFigure
        function ESCOTestUIFigureCloseRequest(app, event)
            if app.Settings.Motor.Connected
                app.DisconnectMotor(app);
            end
            app.StopCamera(app);
            pause(0.1);
            delete(app)
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            app.ESCOTestUIFigureCloseRequest(app);
        end

        % Button pushed function: StartCameraButton
        function StartCamera(app, event)
            imaqreset;
            app.Vid = videoinput(app.Settings.Video.Adaptor, app.Settings.Video.DeviceID,app.Settings.Video.Format, "FramesPerTrigger", Inf);
            app.SizeSlider.Limits = [1, app.Settings.Video.VidResX];
            app.UIvideo.PlotBoxAspectRatio = [1, app.Settings.Video.VidResY/app.Settings.Video.VidResX, 1];    % Set Aspect Ration of Image Display to propper dimensions
            triggerconfig(app.Vid,'manual');
            start(app.Vid);
            start(app.CameraDAQTimer);
            app.StartCameraButton.Enable = "off";
            app.StopCameraButton.Enable = "on";
            app.CrosshairPanel.Enable = "on";

        end

        % Button pushed function: StopCameraButton
        function StopCamera(app, event)
            stop(app.CameraDAQTimer);
            delete(app.Vid);
            app.StartCameraButton.Enable = "on";
            app.StopCameraButton.Enable = "off";
            app.CrosshairPanel.Enable = "off";

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ESCOTestUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {447, 447};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {276, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ESCOTestUIFigure = uifigure('Visible', 'off');
            app.ESCOTestUIFigure.Name = 'ESCO Test';
            app.ESCOTestUIFigure.CloseRequestFcn = createCallbackFcn(app, @ESCOTestUIFigureCloseRequest, true);
            app.ESCOTestUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ESCOTestUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.MotorTab = uitab(app.TabGroup);
            app.MotorTab.Title = 'Motor';
            app.ConnectionPanel = uipanel(app.MotorTab);
            app.ConnectionPanel.Title = 'Connection';
            app.PortDropDownLabel = uilabel(app.ConnectionPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.ConnectionPanel);
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectMotor, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectedLampLabel = uilabel(app.ConnectionPanel);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.ConnectionPanel);
            app.DisconnectButton = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectMotor, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.InfoEditFieldLabel = uilabel(app.ConnectionPanel);
            app.InfoEditFieldLabel.Text = 'Info';
            app.InfoEditField = uieditfield(app.ConnectionPanel, 'text');
            app.MotionPanel = uipanel(app.MotorTab);
            app.MotionPanel.Title = 'Motion';
            app.PositionEditFieldLabel = uilabel(app.MotionPanel);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.MotionPanel, 'numeric');
            app.PositionEditField.ValueDisplayFormat = '%.3f';
            app.PositionEditField.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldValueChanged, true);
            app.NegButton = uibutton(app.MotionPanel, 'push');
            app.NegButton.ButtonPushedFcn = createCallbackFcn(app, @NegButtonPushed, true);
            app.NegButton.Text = '<< Neg';
            app.AmountEditFieldLabel = uilabel(app.MotionPanel);
            app.AmountEditFieldLabel.Text = 'Amount';
            app.AmountEditField = uieditfield(app.MotionPanel, 'numeric');
            app.AmountEditField.ValueDisplayFormat = '%.3f';
            app.PosButton = uibutton(app.MotionPanel, 'push');
            app.PosButton.ButtonPushedFcn = createCallbackFcn(app, @PosButtonPushed, true);
            app.PosButton.Text = 'Pos >>';
            app.ZeroButton = uibutton(app.MotionPanel, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.STOPMOVEButton = uibutton(app.MotionPanel, 'push');
            app.STOPMOVEButton.ButtonPushedFcn = createCallbackFcn(app, @STOPMOVEButtonPushed, true);
            app.STOPMOVEButton.Text = 'STOP MOVE';
            app.CameraTab = uitab(app.TabGroup);
            app.CameraTab.Title = 'Camera';
            app.CameraPanel = uipanel(app.CameraTab);
            app.CameraPanel.Title = 'Camera';
            app.StartCameraButton = uibutton(app.CameraPanel, 'push');
            app.StartCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartCamera, true);
            app.StartCameraButton.Text = 'Start Camera';
            app.StopCameraButton = uibutton(app.CameraPanel, 'push');
            app.StopCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StopCamera, true);
            app.StopCameraButton.Text = 'Stop Camera';
            app.CrosshairPanel = uipanel(app.CameraPanel);
            app.CrosshairPanel.Title = 'Crosshair';
            app.ShowCrosshairCheckBox = uicheckbox(app.CrosshairPanel);
            app.ShowCrosshairCheckBox.Text = 'Show Crosshair';
            app.SizeSliderLabel = uilabel(app.CrosshairPanel);
            app.SizeSliderLabel.Text = 'Size';
            app.SizeSlider = uislider(app.CrosshairPanel);
            app.SizeSlider.Limits = [1 1000];
            app.SizeSlider.MinorTicks = [];
            app.SizeSlider.Value = 1;
            app.OffsetXSpinnerLabel = uilabel(app.CrosshairPanel);
            app.OffsetXSpinnerLabel.Text = 'Offset X';
            app.OffsetXSpinner = uispinner(app.CrosshairPanel);
            app.OffsetXSpinner.ValueDisplayFormat = '%.0f';
            app.OffsetYSpinnerLabel = uilabel(app.CrosshairPanel);
            app.OffsetYSpinnerLabel.Text = 'Offset Y';
            app.OffsetYSpinner = uispinner(app.CrosshairPanel);
            app.OffsetYSpinner.ValueDisplayFormat = '%.0f';
            app.ShowCircleCheckBox = uicheckbox(app.CrosshairPanel);
            app.ShowCircleCheckBox.Text = 'Show Circle';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIvideo = uiaxes(app.RightPanel);
            app.UIvideo.XLimitMethod = 'tight';
            app.UIvideo.YLimitMethod = 'tight';
            app.UIvideo.XTick = [];
            app.UIvideo.YTick = [];
            % Show the figure after all components are created
            app.ESCOTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESCO_Test_GUI_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ESCOTestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ESCOTestUIFigure)
        end
    end
end